classdef AbbasCentral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SortBottomByTopCheckBox        matlab.ui.control.CheckBox
        MatchcLimCheckBox              matlab.ui.control.CheckBox
        CLimSlider_2                   matlab.ui.control.RangeSlider
        ColorLimitsLabel_2             matlab.ui.control.Label
        CLimSlider                     matlab.ui.control.RangeSlider
        ColorLimitsLabel               matlab.ui.control.Label
        LastEventCheckBox_2            matlab.ui.control.CheckBox
        FirstEventCheckBox_2           matlab.ui.control.CheckBox
        LastEventCheckBox              matlab.ui.control.CheckBox
        FirstEventCheckBox             matlab.ui.control.CheckBox
        SortPopBottomCheckbox          matlab.ui.control.CheckBox
        SortPopTopCheckbox             matlab.ui.control.CheckBox
        BottomSlider                   matlab.ui.control.RangeSlider
        Label                          matlab.ui.control.Label
        TopSlider                      matlab.ui.control.RangeSlider
        Label_2                        matlab.ui.control.Label
        ShowLegendCheckBox_2           matlab.ui.control.CheckBox
        ShowLegendCheckBox             matlab.ui.control.CheckBox
        SessionLabel                   matlab.ui.control.Label
        SubjectLabel                   matlab.ui.control.Label
        LowerPanelLabel                matlab.ui.control.Label
        UpperPanelLabel                matlab.ui.control.Label
        PriorToEventEditField_2        matlab.ui.control.EditField
        ExcludeByStateEditField_2      matlab.ui.control.EditField
        PriorToStateEditField_2        matlab.ui.control.EditField
        WithinStateEditField_2         matlab.ui.control.EditField
        BpodEventEditField_2           matlab.ui.control.EditField
        SankeyOutEditField             matlab.ui.control.EditField
        SankeyOutEditFieldLabel        matlab.ui.control.Label
        SankeyInEditField              matlab.ui.control.EditField
        SankeyInEditFieldLabel         matlab.ui.control.Label
        NeuronSetListBox_2             matlab.ui.control.ListBox
        NeuronSetListBox_2Label        matlab.ui.control.Label
        NeuronSetListBox               matlab.ui.control.ListBox
        NeuronSetListBoxLabel          matlab.ui.control.Label
        RemoveBottomNeuronSetButton_2  matlab.ui.control.Button
        AddBottomNeuronSetButton_2     matlab.ui.control.Button
        AddTopNeuronSetButton_2        matlab.ui.control.Button
        RemoveTopNeuronSetButton_2     matlab.ui.control.Button
        DisplayRegion                  matlab.ui.control.Label
        DisplayChannel                 matlab.ui.control.Label
        DisplayCluster                 matlab.ui.control.Label
        RegionDropDown                 matlab.ui.control.DropDown
        RegionDropDownLabel            matlab.ui.control.Label
        StimTypesListBox_2             matlab.ui.control.ListBox
        StimTypesListBox_2Label        matlab.ui.control.Label
        StimTypesListBox               matlab.ui.control.ListBox
        StimTypesListBoxLabel          matlab.ui.control.Label
        NextSessionButton              matlab.ui.control.Button
        PrevSessionButton              matlab.ui.control.Button
        LoadNewSessionsButton          matlab.ui.control.Button
        RemoveBottomPresetButton       matlab.ui.control.Button
        AddBottomPresetButton          matlab.ui.control.Button
        AddTopPresetButton             matlab.ui.control.Button
        RemoveTopPresetButton          matlab.ui.control.Button
        PresetListBox_2                matlab.ui.control.ListBox
        PresetListBox_2Label           matlab.ui.control.Label
        PresetListBox                  matlab.ui.control.ListBox
        PresetListBoxLabel             matlab.ui.control.Label
        CWTPowerButton                 matlab.ui.control.Button
        CalculateAllPowerButton        matlab.ui.control.Button
        PlotCurrentSettingsButton      matlab.ui.control.Button
        FiletypeButtonGroup            matlab.ui.container.ButtonGroup
        SVGButton                      matlab.ui.control.RadioButton
        PNGButton                      matlab.ui.control.RadioButton
        FIGButton                      matlab.ui.control.RadioButton
        FiletypeButtonGroup_2          matlab.ui.container.ButtonGroup
        SVGButton_2                    matlab.ui.control.RadioButton
        PNGButton_2                    matlab.ui.control.RadioButton
        FIGButton_2                    matlab.ui.control.RadioButton
        AutomaticNamesSwitch           matlab.ui.control.Switch
        AutomaticNamesSwitch_2         matlab.ui.control.Switch
        AutomaticNamesSwitchLabel      matlab.ui.control.Label
        AutomaticNamesSwitch_2Label    matlab.ui.control.Label
        PriorToEventEditField          matlab.ui.control.EditField
        PriorToEventEditFieldLabel     matlab.ui.control.Label
        PriorToStateEditField          matlab.ui.control.EditField
        PriorToStateEditFieldLabel     matlab.ui.control.Label
        ExcludeByStateEditField        matlab.ui.control.EditField
        ExcludeByStateEditFieldLabel   matlab.ui.control.Label
        MinimumFRSpinner               matlab.ui.control.Spinner
        MinimumFRSpinnerLabel          matlab.ui.control.Label
        SavebottomplotButton           matlab.ui.control.Button
        SavetopplotButton              matlab.ui.control.Button
        NextNeuronButton               matlab.ui.control.Button
        PrevNeuronButton               matlab.ui.control.Button
        OutcomeListBox_2               matlab.ui.control.ListBox
        OutcomeListBox_2Label          matlab.ui.control.Label
        OutcomeListBox                 matlab.ui.control.ListBox
        OutcomeListBoxLabel            matlab.ui.control.Label
        BottomPanelOptionsLabel        matlab.ui.control.Label
        AlignmentDropDown_2            matlab.ui.control.DropDown
        AlignmentDropDown_2Label       matlab.ui.control.Label
        NeuronDropDown_2               matlab.ui.control.DropDown
        NeuronDropDown_2Label          matlab.ui.control.Label
        SeparatePlotOptionsCheckBox    matlab.ui.control.CheckBox
        TrialTypesListBox_2            matlab.ui.control.ListBox
        TrialTypesListBox_2Label       matlab.ui.control.Label
        LFPChannelButtonGroup_2        matlab.ui.container.ButtonGroup
        Chan32_2                       matlab.ui.control.ToggleButton
        Chan31_2                       matlab.ui.control.ToggleButton
        Chan30_2                       matlab.ui.control.ToggleButton
        Chan29_2                       matlab.ui.control.ToggleButton
        Chan28_2                       matlab.ui.control.ToggleButton
        Chan27_2                       matlab.ui.control.ToggleButton
        Chan26_2                       matlab.ui.control.ToggleButton
        Chan25_2                       matlab.ui.control.ToggleButton
        Chan24_2                       matlab.ui.control.ToggleButton
        Chan23_2                       matlab.ui.control.ToggleButton
        Chan22_2                       matlab.ui.control.ToggleButton
        Chan21_2                       matlab.ui.control.ToggleButton
        Chan20_2                       matlab.ui.control.ToggleButton
        Chan19_2                       matlab.ui.control.ToggleButton
        Chan18_2                       matlab.ui.control.ToggleButton
        Chan17_2                       matlab.ui.control.ToggleButton
        Chan16_2                       matlab.ui.control.ToggleButton
        Chan15_2                       matlab.ui.control.ToggleButton
        Chan14_2                       matlab.ui.control.ToggleButton
        Chan13_2                       matlab.ui.control.ToggleButton
        Chan12_2                       matlab.ui.control.ToggleButton
        Chan11_2                       matlab.ui.control.ToggleButton
        Chan10_2                       matlab.ui.control.ToggleButton
        Chan9_2                        matlab.ui.control.ToggleButton
        Chan8_2                        matlab.ui.control.ToggleButton
        Chan7_2                        matlab.ui.control.ToggleButton
        Chan6_2                        matlab.ui.control.ToggleButton
        Chan5_2                        matlab.ui.control.ToggleButton
        Chan4_2                        matlab.ui.control.ToggleButton
        Chan3_2                        matlab.ui.control.ToggleButton
        Chan2_2                        matlab.ui.control.ToggleButton
        Chan1_2                        matlab.ui.control.ToggleButton
        TopPanelButtonGroup            matlab.ui.container.ButtonGroup
        DigraphButton_Top_2            matlab.ui.control.ToggleButton
        PopulationButton_Top_2         matlab.ui.control.ToggleButton
        SankeyButton_Top               matlab.ui.control.ToggleButton
        PopulationButton_Top           matlab.ui.control.ToggleButton
        PerformanceButton_Top          matlab.ui.control.ToggleButton
        DigraphButton_Top              matlab.ui.control.ToggleButton
        SpikePhaseButton_Top           matlab.ui.control.ToggleButton
        PowerButton_Top                matlab.ui.control.ToggleButton
        PSTHButton_Top                 matlab.ui.control.ToggleButton
        RasterButton_Top               matlab.ui.control.ToggleButton
        BottomPanelButtonGroup         matlab.ui.container.ButtonGroup
        DigraphButton_Bottom_2         matlab.ui.control.ToggleButton
        PopulationButton_Bottom_2      matlab.ui.control.ToggleButton
        SankeyButton_Bottom            matlab.ui.control.ToggleButton
        PopulationButton_Bottom        matlab.ui.control.ToggleButton
        PerformanceButton_Bottom       matlab.ui.control.ToggleButton
        DigraphButton_Bottom           matlab.ui.control.ToggleButton
        SpikePhaseButton_Bottom        matlab.ui.control.ToggleButton
        PowerButton_Bottom             matlab.ui.control.ToggleButton
        PSTHButton_Bottom              matlab.ui.control.ToggleButton
        RasterButton_Bottom            matlab.ui.control.ToggleButton
        WithinStateEditField           matlab.ui.control.EditField
        WithinStateEditFieldLabel      matlab.ui.control.Label
        UpperFreqLimitEditField        matlab.ui.control.NumericEditField
        UpperFreqLimitEditFieldLabel   matlab.ui.control.Label
        LowerFreqLimitEditField        matlab.ui.control.NumericEditField
        LowerFreqLimitEditFieldLabel   matlab.ui.control.Label
        PreviousTrialButton            matlab.ui.control.Button
        NextTrialButton                matlab.ui.control.Button
        BpodEventEditField             matlab.ui.control.EditField
        BpodEventEditFieldLabel        matlab.ui.control.Label
        OffsetsecondsEditField         matlab.ui.control.NumericEditField
        OffsetsecondsEditFieldLabel    matlab.ui.control.Label
        EdgessecondsEditField          matlab.ui.control.NumericEditField
        EdgessecondsEditFieldLabel     matlab.ui.control.Label
        LFPChannelButtonGroup          matlab.ui.container.ButtonGroup
        Chan32                         matlab.ui.control.ToggleButton
        Chan31                         matlab.ui.control.ToggleButton
        Chan30                         matlab.ui.control.ToggleButton
        Chan29                         matlab.ui.control.ToggleButton
        Chan28                         matlab.ui.control.ToggleButton
        Chan27                         matlab.ui.control.ToggleButton
        Chan26                         matlab.ui.control.ToggleButton
        Chan25                         matlab.ui.control.ToggleButton
        Chan24                         matlab.ui.control.ToggleButton
        Chan23                         matlab.ui.control.ToggleButton
        Chan22                         matlab.ui.control.ToggleButton
        Chan21                         matlab.ui.control.ToggleButton
        Chan20                         matlab.ui.control.ToggleButton
        Chan19                         matlab.ui.control.ToggleButton
        Chan18                         matlab.ui.control.ToggleButton
        Chan17                         matlab.ui.control.ToggleButton
        Chan16                         matlab.ui.control.ToggleButton
        Chan15                         matlab.ui.control.ToggleButton
        Chan14                         matlab.ui.control.ToggleButton
        Chan13                         matlab.ui.control.ToggleButton
        Chan12                         matlab.ui.control.ToggleButton
        Chan11                         matlab.ui.control.ToggleButton
        Chan10                         matlab.ui.control.ToggleButton
        Chan9                          matlab.ui.control.ToggleButton
        Chan8                          matlab.ui.control.ToggleButton
        Chan7                          matlab.ui.control.ToggleButton
        Chan6                          matlab.ui.control.ToggleButton
        Chan5                          matlab.ui.control.ToggleButton
        Chan4                          matlab.ui.control.ToggleButton
        Chan3                          matlab.ui.control.ToggleButton
        Chan2                          matlab.ui.control.ToggleButton
        Chan1                          matlab.ui.control.ToggleButton
        TrialTypesListBox              matlab.ui.control.ListBox
        TrialTypesListBoxLabel         matlab.ui.control.Label
        AlignmentDropDown              matlab.ui.control.DropDown
        AlignmentDropDownLabel         matlab.ui.control.Label
        TopPanel                       matlab.ui.container.Panel
        BottomPanel                    matlab.ui.container.Panel
        NeuronDropDown                 matlab.ui.control.DropDown
        NeuronDropDownLabel            matlab.ui.control.Label
        SessionDropDown                matlab.ui.control.DropDown
        SessionDropDownLabel           matlab.ui.control.Label
        SubjectDropDown                matlab.ui.control.DropDown
        SubjectDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)

        % CORE
        experiment          % ExpManager loaded in app
        goodNeurons         % Neurons that are above the minimum firing rate
        diffPlotOptions     % Bool controlling whether both plots are controlled by the same alignment/neuron set
        matchCLim           % If population plots share a color axis
        topSelection        % The type of plot on the top panel
        bottomSelection
        defaultTopSave      % Bool controlling if saving top panel gets default name
        defaultBottomSave
        topSaveFormat       % String determining save format of top panel
        bottomSaveFormat
        topSliderRange      % Vector giving the range values for top pop slider
        bottomSliderRange
        sortPopTop          % Bool controlling sorting of top panel population heatmaps
        sortPopBottom
        sortedIdx           % Index for top neurons
        shareSorting        % Bool determining if bottom sorting is the same as top
        updatePop           % Bool controlling updating of population-level method plots
        showTopLegend = true
        showBottomLegend = true

        % CURRENT
        currentObject       % object loaded into app
        currentSubject      % name of the current subject
        currentSession      % name of the current session
        currentChannel      % LFP channel looked at
        currentNeuron       % Neuron number currently selected
        currentRegion       % Which region to display neurons from
        neuronIdx           % Index of current neuron in goodNeurons
        numNeurons          % Total number of neurons in this session

        % PARAMS
        alignment           % Task alignment currently selected
        alignmentUpper
        alignmentLower
        bpodEvent           % Boolean to determine if functions should use find_event or find_bpod_event
        edges               % Distance backward and forward from alignment (sec)
        binWidth            % bin width (ms)
        offset              % Offset backward (negative) or forward (positive) from alignment
        withinState         % States to search for bpod event within (char/cell)
        priorToState        % Search for bpod event prior to this state
        priorToEvent        % Search for bpod event prior to this event
        excludeByState      % Exclude bpod events that happen in this state
        trialTypes          % Trial types that gets loaded into the plot function
        trialTypesUpper     % Currently selected trial types to examine (upper panel)
        trialTypesLower     % Currently selected trial types to examine (lower panel)
        stimTypes           % Stim types that gets loaded into the plot function
        stimTypesUpper      % Currently selected stim types to examine (upper panel)
        stimTypesLower      % Currently selected stim types to examine (lower panel)
        outcomes            % Outcome that gets loaded into the plot function
        outcomesUpper       % Currently selected outcomes to examine (upper panel)
        outcomesLower       % Currently selected outcomes to examine (lower panel)
        trialSets           % Trial set that gets loaded into plot function
        trialSetsUpper      % Currently selected trial set to examine (upper panel)
        trialSetsLower      % Currently selected trial set to examine (lower panel)
        minFR               % Only neurons above this value will be available

        savedPresets        % Structure containing all saved presets
        preset              % PresetManager to be loaded in plot function
        presetUpper         % Upper panel PresetManager
        presetLower         % Lower panel PresetManager

        % LFP
        power               % Structure that holds calculated LFP data. Subfield layout: session -> alignment -> trialType -> outcome
        freqs               % Frequency labels for power plots
        freqLimits          % Frequency limits for bandpass function
        currentTrial        % Currently selected trial
        maxTrials           % Total number of bpod trials
        maxTrialsUpper
        maxTrialsLower
        filteredSignal      % Stored filtered signal for one session
        cwtFileName         % The path to the cwt h5 file
        figSaveDir          % The most recent location a figure was saved
    end
    
    methods (Access = private)

        function plot_current_type(app, panel)
            if isempty(app.alignment)
                return
            end
%             app.RotationEndOfState.Visible = 'off';
            plotPanelOptions = {'Raster', 'PSTH', 'Population', 'Power', 'Spike Phase', 'Digraph', 'Performance', 'Sankey', 'LabGym', 'Position'};
            plotPanelFcns =  {@raster_fcn, @psth_fcn, @pop_fcn, @pwr_fcn, @spike_phase_fcn, @digraph_fcn, @perf_fcn, @sankey_fcn, @labgym_fcn, @pos_fcn};
            fcnMap = containers.Map(plotPanelOptions, plotPanelFcns);
            try
                app.DisplayRegion.Text = app.currentObject.spikes(app.currentNeuron).region;
            catch
                app.DisplayRegion.Text = 'Region';
            end
            try
                app.DisplayCluster.Text = 'Cluster';
            catch
                app.DisplayCluster.Text = ['Cluster ' num2str(app.currentObject.spikes(app.currentNeuron).cluster)];
            end
            try
                app.DisplayChannel.Text = ['Channel ' num2str(app.currentObject.spikes(app.currentNeuron).channel)];
            catch
                app.DisplayChannel.Text = 'Channel';
            end
            if ~strcmp(app.topSelection, 'Sankey') && ~strcmp(app.bottomSelection, 'Sankey')
                app.SankeyInEditField.Visible = "off";
                app.SankeyOutEditField.Visible = "off";
            end
            set(panel, 'BackgroundColor', 'w')
            switch panel
                case app.TopPanel
                    if (app.updatePop && any(strcmp(app.topSelection, {'Population', 'Digraph', 'Sankey', 'Performance', 'Power', 'LabGym', 'Position'}))) || any(strcmp(app.topSelection, {'Raster', 'PSTH', 'Spike Phase'}))
                        delete(app.TopPanel.Children)
                    end
                    % if isprop(app, 'TopSlider')
                    %     app.TopSlider.Visible = 'off';
                    % end
                    app.outcomes = app.outcomesUpper;
                    app.trialTypes = app.trialTypesUpper;
                    app.stimTypes = app.stimTypesUpper;
                    app.preset.copy(app.presetUpper);
                    app.preset.panel = app.TopPanel;
                    app.preset
                    topPlot = fcnMap(app.topSelection);
                    topPlot(app, app.TopPanel);

                case app.BottomPanel
                    if (app.updatePop && any(strcmp(app.bottomSelection, {'Population', 'Digraph', 'Sankey', 'Performance', 'Power', 'LabGym', 'Position'}))) || any(strcmp(app.bottomSelection, {'Raster', 'PSTH', 'Spike Phase'}))
                        delete(app.BottomPanel.Children)
                    end
                    % if isprop(app, 'BottomSlider')
                    %     app.BottomSlider.Visible = 'off';
                    % end
                    app.outcomes = app.outcomesLower;
                    app.trialTypes = app.trialTypesLower;
                    app.stimTypes = app.stimTypesLower;
                    app.preset.copy(app.presetLower);
                    app.preset.panel = app.BottomPanel;
                    bottomPlot = fcnMap(app.bottomSelection);
                    bottomPlot(app, app.BottomPanel);
            end
            app.update_legends()
        end

        function raster_fcn(app, panel)
            if strcmp("Select a neuron", string(app.NeuronDropDown.Value))
                return
            end
            if app.currentNeuron <= numel(app.currentObject.spikes)
                app.currentObject.raster(app.currentNeuron, 'preset', app.preset);
            end
        end

        function psth_fcn(app, panel)
            if strcmp("Select a neuron", string(app.NeuronDropDown.Value))
                return
            end
            if app.currentNeuron <= numel(app.currentObject.spikes)
                app.currentObject.psth(app.currentNeuron, 'preset', app.preset);
            end
        end

        function pwr_fcn(app, panel)
            if isempty(app.trialTypes)
                currentTT = 'All';
            else
                currentTT = app.trialTypes{1};
            end
            if isempty(app.outcomes)
                currentOutcome = 'All';
            else
                currentOutcome = app.outcomes{1};
            end
            h5String = strcat("/", fullfile(app.currentSubject, app.currentSession, app.alignment, currentTT, currentOutcome));
            h5String = regexprep(h5String, "\", "/");
            try
                freqLabels = h5read(app.cwtFileName, '/frequencies');
                pwrMat = h5read(app.cwtFileName, h5String);
                pwrMat = num2cell(pwrMat, [1 2]);
                switch panel
                    case app.TopPanel
                        cLimits = min(max(app.presetUpper.cLim, 0), 10);
                    case app.BottomPanel
                        cLimits = min(max(app.presetLower.cLim, 0), 10);
                end
                plot_cwt(pwrMat, app.currentChannel, freqLabels, cLimits * 5000, panel);
            catch
                return
            end
        end

        function spike_phase_fcn(app, panel)
            app.LFPChannelButtonGroup.Visible = 'on';
            app.BottomPanel.AutoResizeChildren = 'off';
            allSpikes = app.currentObject.bin_neuron(app.alignment, app.currentNeuron, 'trialType', app.trialTypes, 'outcome', app.outcomes, ...
                'edges', [-app.edges app.edges], 'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState);
            binnedTrial = [zeros(1, size(allSpikes, 2)); allSpikes(app.currentTrial, :)];
            f = figure('Visible', 'off');
            t = tiledlayout(f, 1, 1, 'Visible', 'off'); 
            ax1 = axes(t);
            plotSpikeRaster(boolean(binnedTrial), 'PlotType', 'vertline', 'VertSpikeHeight', 2.5);
            if isempty(app.filteredSignal)
                d = uiprogressdlg(app.UIFigure, 'Title', "Filtering signal...", 'Indeterminate', 'on');
                drawnow
                app.filteredSignal = app.currentObject.filter_signal(app.alignment, 'freqLimits', app.freqLimits, ...
                    'trialType', app.trialTypes, 'outcome', app.outcomes, 'edges', [-app.edges app.edges], ...
                    'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState, 'filter', 'butter');
                close(d)
            end
            ax2 = axes(t);
            plot(ax2, app.filteredSignal{app.currentTrial}(:, app.currentChannel))
            ax2.XAxisLocation = 'top';
            ax2.YAxisLocation = 'right';
            set(gca, 'Xcolor', 'w', 'Ycolor', 'w')
            set(f, 'Color', 'k')
            set(gca, 'XTick', [])
            set(gca, 'YTick', [])
            ax2.Color = 'none';
            ax1.Box = 'off';
            ax2.Box = 'off';
            title(strcat("Current trial: ", num2str(app.currentTrial)))
            copyobj(t.Children, panel)
            close(f)
        end
        
        function pop_fcn(app, panel)        
            if ~app.updatePop
                return
            end
            d = uiprogressdlg(app.UIFigure, 'Title', "Plotting mean population response...", 'Indeterminate', 'on');
            drawnow
            baseTrials = find(app.currentObject.trial_intersection('preset', app.preset));
            zEdges = [-app.edges, app.edges] + app.offset;
            switch panel
                case app.TopPanel
                    if app.sortPopTop
                        sortVec = app.topSliderRange;
                    else
                        sortVec = [];
                    end
                    if app.shareSorting           
                        app.sortedIdx = app.currentObject.mean_population_response('preset', app.preset, 'baseline', app.alignmentUpper, 'bWindow', zEdges, 'baseTrials', baseTrials, 'sortBy', sortVec, 'panel', []);
                    end
                case app.BottomPanel
                    if app.sortPopBottom
                        sortVec = app.bottomSliderRange;
                    else
                        sortVec = [];
                    end
                    if app.shareSorting
                        app.currentObject.mean_population_response('preset', app.preset, 'baseline', app.alignmentLower, 'bWindow', zEdges, 'baseTrials', baseTrials, 'orderBy', app.sortedIdx)
                        app.update_bottom_sort_slider
                        return
                    end
            end
            app.currentObject.mean_population_response('preset', app.preset, 'baseline', app.alignment, 'bWindow', zEdges, 'baseTrials', baseTrials, 'sortBy', sortVec, 'bpodBaseline', app.bpodEvent);
            app.update_top_sort_slider
            app.update_bottom_sort_slider

            close(d)
        end

        function digraph_fcn(app, panel)      
            if ~app.updatePop
                return
            end
            d = uiprogressdlg(app.UIFigure, 'Title', "Updating Directed Graph", 'Indeterminate', 'on');
            drawnow
            if isempty(app.alignment)
                close(d)
                return
            end
            delete(panel.Children)
            app.currentObject.plot_digraph(app.currentObject.trialize_mono_excitatory('preset', app.preset), panel);
            close(d)
            if isfield(panel.Children, 'Children')
                panel.Children.Children(1).ButtonDownFcn = @app.scatterClickFcn;
            end
        end

        function perf_fcn(app, panel)
            app.currentObject.plot_performance(1, 'preset', app.preset);
        end

        function sankey_fcn(app, panel)
            app.SankeyInEditField.Visible = "on";
            app.SankeyOutEditField.Visible = "on";
            sankeyIn = app.SankeyInEditField.Value;
            sankeyOut = app.SankeyOutEditField.Value;
            app.currentObject.state_sankey('preset', app.preset, 'inputStates', sankeyIn, 'outputStates', sankeyOut);
        end

        function labgym_fcn(app, panel)
            app.currentObject.plot_LabGym_behaviors('preset', app.preset);
        end
        
        function pos_fcn(app, panel)
            app.currentObject.plot_centroid('preset', app.preset);
        end
        

        function switch_sessions(app, sessionNo)
            d = uiprogressdlg(app.UIFigure, 'Title', "Changing sessions, Randall...", 'Indeterminate', 'on');
            drawnow
            % Load list of subjects
            allSubs = app.experiment.metadata.subjects;
            numSubs = numel(allSubs);
            currentSubNum = find(strcmp(allSubs, app.currentSubject));
            subSessions = app.experiment.subset('animal', app.currentSubject);
            % Change subject if moving forward from last session or
            % backwards from first
            if ~ismember(find(subSessions), sessionNo)
                newSubNum = find(strcmp(allSubs, app.experiment.sessions(sessionNo).info.name));
                app.currentSubject = allSubs{newSubNum};
                app.SubjectDropDown.Value = app.currentSubject;
            end
            % Load BehDat object and change session dropdown to match
            app.currentObject = app.experiment.sessions(sessionNo);
            [~, sessName] = fileparts(app.currentObject.info.path);
            app.currentSession = sessName;
            app.SessionLabel.Text = sessName;
            app.SubjectLabel.Text = app.currentSubject;
            subSessions = app.experiment.subset('animal', app.currentSubject);
            [x{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.experiment.sessions, 'uni', 0);
            sessionNames = x{2}(subSessions);
            sessionNames = [{'Select a session'}, sessionNames];
            app.SessionDropDown.Items = sessionNames;
            app.SessionDropDown.Value = sessName;
            % Reset spike data
            app.currentNeuron = 1;
            app.numNeurons = numel(app.currentObject.spikes);
            
            % if ~isempty(app.currentObject.spikes)
            %     allFR = extractfield(app.currentObject.spikes, 'fr');
            %     app.goodNeurons = find(allFR >= app.minFR);
            %     neuronString = arrayfun(@num2str, app.goodNeurons, 'uni', 0);
            % else
            %     allFR = [];
            %     app.goodNeurons = [];
            %     neuronString = [];
            % end
            % neuronString = [{'Select a neuron'}, neuronString];
            % app.NeuronDropDown.Items = neuronString;
            alignments = fields(app.currentObject.timestamps.keys)';
                appAlignments = [{'Select an alignment', 'Bpod'}, alignments];
            app.AlignmentDropDown.Items = appAlignments;
            app.AlignmentDropDown_2.Items = appAlignments;
            % Populate sub-event selection fields
            availableTT = fields(app.currentObject.info.trialTypes)';
            availableTT = [{'All'}, availableTT];
            app.TrialTypesListBox.Items = availableTT;
            app.TrialTypesListBox_2.Items = availableTT;
            if isfield(app.currentObject.info, 'stimTypes') && ~isempty(app.currentObject.info.stimTypes)
                availableStimTypes = fields(app.currentObject.info.stimTypes)';
                availableStimTypes = [{'All'}, availableStimTypes];
            else
                availableStimTypes = {'None'};
            end
            app.StimTypesListBox.Items = availableStimTypes;
            app.StimTypesListBox_2.Items = availableStimTypes;   
            availableOutcomes = fields(app.currentObject.info.outcomes)';
            availableOutcomes = [{'All'}, availableOutcomes];         
            app.OutcomeListBox.Items = availableOutcomes;
            app.OutcomeListBox_2.Items = availableOutcomes;
            app.LowerFreqLimitEditField.Value = 6;
            app.UpperFreqLimitEditField.Value = 12;
            app.freqLimits = [6 12];
            app.filteredSignal = [];
            % Structure subfield layout: session -> alignment -> trialType -> outcome

            if ~isfield(app.power, sessName)
                structName = regexprep(sessName, '-', '_');
                app.power.(structName) = struct();       
            end
            app.currentTrial = 1;
            app.maxTrials = app.currentObject.bpod.session.nTrials;
            app.show_button_group            
            try
                regionNames = extractfield(app.currentObject.spikes, 'region');
                goodRegionNames = cellfun(@(x) ~isempty(x), regionNames);
                uniqueRegions = unique(regionNames(goodRegionNames));
                app.RegionDropDown.Items = [{'All'}, uniqueRegions];
            catch
                app.RegionDropDown.Items = {'All'};
            end
            % app.plot_current_type(app.TopPanel)
            % app.plot_current_type(app.BottomPanel)
            app.find_good_neurons
            close(d)
        end

        function reset_power_struct(app)
            app.power = struct();
            sessionName = regexprep(app.currentSession, '[()-]', '_');
            app.power.(sessionName) = struct();       
        end

        function show_button_group(app)
            sessionName = regexprep(app.currentSession, '[()-]', '_');
            [~, sessName] = fileparts(sessionName);
            if isempty(app.trialTypes) || iscell(app.trialTypes)
                trialTypesStruct = 'All';
            else
                trialTypesStruct = app.trialTypes;
            end
            if isempty(app.outcomes) || iscell(app.outcomes)
                outcomesStruct = 'All';
            else
                outcomesStruct = app.outcomes;
            end
            % if ~is_subfield(app.power.(sessName), sprintf("%s.%s.%s", app.alignment, trialTypesStruct, outcomesStruct))
            %     app.LFPChannelButtonGroup.Visible = 'off';
            % else
                app.LFPChannelButtonGroup.Visible = 'on';
            % end
        end

        function neuronSelected = scatterClickFcn(app, hobj, event)
            %  FIND NEAREST (X,Y,Z) COORDINATE TO MOUSE CLICK
            % Inputs:
            %  hObj: the axes
            %  event: info about mouse click
            % OUTPUT
            %  coordinateSelected: the (x,y) coordinate you selected
            %  minIDx: The index of your inputs that match coordinateSelected. 
            x = hobj.XData;
            y = hobj.YData;
            pt = event.IntersectionPoint(1:2);      % The (x0,y0) coordinate you just selected
            coordinates = [x(:),y(:)];              % matrix of your input coordinates
            dist = pdist2(pt,coordinates);          % distance between your selection and all points
            [~, neuronSelected] = min(dist);        % index of minimum distance to points
            app.currentNeuron = neuronSelected;
            app.NeuronDropDown.Value = num2str(neuronSelected);
            app.plot_current_type;
        end

        function save_figure(app, panel, defaultName)
            try
                app.figSaveDir = uigetdir(app.figSaveDir);
            catch
                app.figSaveDir = uigetdir;
            end
            
            if defaultName
                switch panel
                    case app.TopPanel
                        alignmentString = app.presetUpper.event;
                        if isempty(app.presetUpper.outcome)
                            outcomeString = 'allOutcomes';
                        else
                            outcomeString = join(app.presetUpper.outcome, '+');
                        end
                        if isempty(app.presetUpper.trialType)
                            trialTypeString = 'allTrialTypes';
                        else
                            trialTypeString = join(app.presetUpper.trialType, '+');
                        end
                        if isempty(app.presetUpper.stimType)
                            stimTypeString = 'allStimTypes';
                        else
                            stimTypeString = join(app.presetUpper.stimType, '+');
                        end
                        saveFormat = app.topSaveFormat;
                        plotType = app.topSelection;
                    case app.BottomPanel
                        alignmentString = app.presetLower.event;
                        if isempty(app.presetLower.outcome)
                            outcomeString = 'allOutcomes';
                        else
                            outcomeString = join(app.presetLower.outcome, '+');
                        end
                        if isempty(app.presetLower.trialType)
                            trialTypeString = 'allTrialTypes';
                        else
                            trialTypeString = join(app.presetLower.trialType, '+');
                        end
                        if isempty(app.presetLower.stimType)
                            stimTypeString = 'allStimTypes';
                        else
                            stimTypeString = join(app.presetLower.stimType, '+');
                        end
                        saveFormat = app.bottomSaveFormat;
                        plotType = app.bottomSelection;
                end
                fileName = strcat(plotType, '_', app.currentSubject, '_', app.currentSession, '_', num2str(app.currentNeuron), '_', alignmentString, ...
                    '_', trialTypeString, '_', outcomeString, '_', stimTypeString);
                if iscell(fileName)
                    fileName = fileName{1};
                end
            else
                fileName = inputdlg('Please enter a filename', 'Save panel');
                fileName = fileName{1};
                switch panel
                    case app.TopPanel
                        saveFormat = app.topSaveFormat;
                    case app.BottomPanel
                        saveFormat = app.bottomSaveFormat;
                end
            end
            savePath = fullfile(app.figSaveDir, strcat(fileName, saveFormat));
            h = figure();
            set(h, 'color', 'w')
            copyobj(panel.Children, h);
            saveas(h, savePath);
            close(h)
        end

        function toggle_bpod_upper(app, val)
            app.BpodEventEditField.Visible = val;
            app.WithinStateEditField.Visible = val;
            app.ExcludeByStateEditField.Visible = val;
            app.PriorToStateEditField.Visible = val;
            app.PriorToEventEditField.Visible = val;
        end

        function toggle_bpod_lower(app, val)
            app.BpodEventEditField_2.Visible = val;
            app.WithinStateEditField_2.Visible = val;
            app.ExcludeByStateEditField_2.Visible = val;
            app.PriorToStateEditField_2.Visible = val;
            app.PriorToEventEditField_2.Visible = val;
        end

        function add_preset(app, panel)
            trialset = inputdlg('Please name the current trialset:', 'Create new trialset');
            if isempty(trialset)
                return
            end
            trialsetField = regexprep(trialset{1}, ' ', '_');
            newTrialset = PresetManager('preset', app.presetUpper);
            app.savedPresets.(trialsetField) = newTrialset;
            app.PresetListBox.Items = fields(app.savedPresets);
            app.PresetListBox_2.Items = fields(app.savedPresets);
            switch panel
                case app.TopPanel
                    app.PresetListBox.Value = trialsetField;
                case app.BottomPanel
                    app.PresetListBox_2.Value = trialsetField;
            end
        end

        function remove_preset(app, trialset)
            if strcmp(trialset, 'none')
                return
            end     
            selection = questdlg(sprintf("Are you sure you wish to delete the trialset %s?", trialset), "Confirm delete trials", ...
                "I'm sure, delete it", "Cancel", "Cancel");
            if strcmp(selection, "Cancel")
                return
            end
            tsToRemove = strcmp(app.PresetListBox.Items, trialset);

            app.PresetListBox.Items = app.PresetListBox.Items(~tsToRemove);
            app.PresetListBox_2.Items = app.PresetListBox_2.Items(~tsToRemove);
            app.savedPresets = rmfield(app.savedPresets, trialset);
        end
        
        function unset_custom_trialset(app)
            app.presetUpper.outcome = [];
        end

        function assign_preset_values(app, preset)

            if preset.bpod
                app.toggle_bpod_upper('on');
                app.AlignmentDropDown.Value = 'Bpod';
                app.bpodEvent = true;
                app.BpodEventEditField.Value = preset.event;
                app.WithinStateEditField.Value = app.fill_bpod_field(preset.withinState);
                app.ExcludeByStateEditField.Value = app.fill_bpod_field(preset.excludeState);
                app.PriorToStateEditField.Value = app.fill_bpod_field(preset.priorToState);
                app.PriorToEventEditField.Value = app.fill_bpod_field(preset.priorToEvent);
            else
                app.AlignmentDropDown.Value = preset.event;
                app.bpodEvent = false;
                app.toggle_bpod_upper('off');
            end

            app.EdgessecondsEditField.Value = preset.edges(2);
            app.OffsetsecondsEditField.Value = preset.offset;
            app.LowerFreqLimitEditField.Value = preset.freqLimits(1);
            app.UpperFreqLimitEditField.Value = preset.freqLimits(2);
            if isempty(preset.trialType)
                ttVal = 'All';
            else
                ttVal = preset.trialType;
            end
            if isempty(preset.outcome)
                outcomeVal = 'All';
            else
                outcomeVal = preset.outcome;
            end
            if all(cellfun(@(x) strcmp(x, 'None'), app.StimTypesListBox.Items))
                stimVal = 'None';
            elseif isempty(preset.stimType)
                stimVal = 'All';
            else
                stimVal = preset.stimType;
            end
            switch preset
                case app.presetUpper
                    app.TrialTypesListBox.Value = ttVal;
                    app.OutcomeListBox.Value = outcomeVal;
                    app.StimTypesListBox.Value = stimVal;
                case app.presetLower
                    app.TrialTypesListBox_2.Value = ttVal;
                    app.OutcomeListBox_2.Value = outcomeVal;
                    app.StimTypesListBox_2.Value = stimVal;
            end
        end
    
        function fieldString = fill_bpod_field(app, presetField)
            if isempty(presetField)
                fieldString = '';
            else
                fieldString = presetField;
            end
        end

        function find_good_neurons(app)
            if ~isempty(app.currentRegion)
                neuronsInRegion = cellfun(@(x) strcmp(x, app.currentRegion), extractfield(app.currentObject.spikes, 'region'));
            else
                neuronsInRegion = true(1, app.numNeurons);
            end
            allFR = extractfield(app.currentObject.spikes, 'fr');
            neuronsAboveThreshold = allFR >= app.minFR;
            % neuronsInSet = 

            app.goodNeurons = find(neuronsInRegion & neuronsAboveThreshold);

            app.presetUpper.subset = app.goodNeurons;
            app.presetLower.subset = app.goodNeurons;
            neuronString = arrayfun(@num2str, app.goodNeurons, 'uni', 0);
            neuronString = [{'Select a neuron'}, neuronString];
            app.NeuronDropDown.Items = neuronString;
            if ~ismember(app.currentNeuron, app.goodNeurons)
                app.NextNeuronButtonPushed;
            end
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
        end

        function add_neuronSet(app)
            neuronSet = inputdlg('Please name the current Neuron Set:', 'Create new neuronSet');
            if isempty(neuronSet)
                return
            end
            neuronSetField = regexprep(neuronSet{1}, ' ', '_');
            newNeuronSet = PresetManager('preset', app.presetUpper);
            app.savedPresets.(neuronSetField) = newNeuronSet;
            app.NeuronSetListBox.Items = fields(app.savedPresets);
            app.NeuronSetListBox_2.Items = fields(app.savedPresets);
            switch panel
                case app.TopPanel
                    app.NeuronSetListBox.Value = neuronSetField;
                case app.BottomPanel
                    app.NeuronSetListBox_2.Value = neuronSetField;
            end
        end
        
        function remove_neuronSet(app, neuronSet)
            if strcmp(neuronSet, 'none')
                return
            end     
            selection = questdlg(sprintf("Are you sure you wish to delete the neuron set %s?", neuronSet), "Confirm delete trials", ...
                "I'm sure, delete it", "Cancel", "Cancel");
            if strcmp(selection, "Cancel")
                return
            end
            tsToRemove = strcmp(app.PresetListBox.Items, trialset);

            app.PresetListBox.Items = app.PresetListBox.Items(~tsToRemove);
            app.PresetListBox_2.Items = app.PresetListBox_2.Items(~tsToRemove);
            app.savedPresets = rmfield(app.savedPresets, trialset);
        end

        function update_legends(app)
            topLgdIdx = arrayfun(@(x) isa(x, 'matlab.graphics.illustration.Legend'), app.TopPanel.Children);
            if any(topLgdIdx)
                topLgd = app.TopPanel.Children(topLgdIdx);
                if app.showTopLegend
                    set(topLgd, 'visible', 'on')
                else
                    set(topLgd, 'visible', 'off')
                end            
            end
            topLgdHeatmap = arrayfun(@(x) isa(x, 'matlab.graphics.chart.HeatmapChart'), app.TopPanel.Children);
            if any(topLgdHeatmap)
                topHeatmap = app.TopPanel.Children(topLgdHeatmap);
                
                if app.showTopLegend
                    set(topHeatmap, 'ColorbarVisible', 'on')
                else
                    set(topHeatmap, 'ColorbarVisible', 'off')
                end
                app.update_top_sort_slider
            end
            bottomLgdIdx = arrayfun(@(x) isa(x, 'matlab.graphics.illustration.Legend'), app.BottomPanel.Children);
            if any(bottomLgdIdx)
                bottomLegend = app.BottomPanel.Children(bottomLgdIdx);
                if app.showBottomLegend
                    set(bottomLegend, 'visible', 'on')
                else
                    set(bottomLegend, 'visible', 'off')
                end
            end
            bottomLgdHeatmap = arrayfun(@(x) isa(x, 'matlab.graphics.chart.HeatmapChart'), app.BottomPanel.Children);
            if any(bottomLgdHeatmap)
                bottomHeatmap = app.BottomPanel.Children(bottomLgdHeatmap);
                if app.showBottomLegend
                    set(bottomHeatmap, 'ColorbarVisible', 'on')
                else
                    set(bottomHeatmap, 'ColorbarVisible', 'off')
                end
                app.update_bottom_sort_slider
            end
        end

        function update_top_sort_slider(app)
            if app.sortPopTop
                panelPosition = app.TopPanel.Position;
                heatmapPosition = app.TopPanel.Children.InnerPosition;
                sliderX = panelPosition(1) + panelPosition(3) * heatmapPosition(1);
                sliderY = panelPosition(2) + panelPosition(4) * heatmapPosition(2);
                sliderWidth = panelPosition(3) * heatmapPosition(3);
                app.TopSlider.Visible = 'on';
                app.TopSlider.MajorTickLabels = {};
                app.TopSlider.Position = [sliderX, sliderY, sliderWidth, heatmapPosition(4)];
            else
                app.TopSlider.Visible = 'off';
            end
        end

        function update_bottom_sort_slider(app)
            if app.sortPopBottom
                panelPosition = app.BottomPanel.Position;
                heatmapPosition = app.BottomPanel.Children.InnerPosition;
                sliderX = panelPosition(1) + panelPosition(3) * heatmapPosition(1);
                sliderY = panelPosition(2) + panelPosition(4) * heatmapPosition(2);
                sliderWidth = panelPosition(3) * heatmapPosition(3);
                app.BottomSlider.Visible = 'on';
                app.BottomSlider.MajorTickLabels = {};
                app.BottomSlider.Position = [sliderX, sliderY, sliderWidth, heatmapPosition(4)];
            else
                app.BottomSlider.Visible = 'off';
            end
        end

        function detect_mouse_over_panel(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.withinState=[];
            app.UIFigure.Visible = 'off';
            LoadExpManager(app);
            s = app.experiment.metadata.subjects;
            s = [{'Select a subject'}, s];
            app.SubjectDropDown.Items = s;
            app.edges = 2;
            app.binWidth = 1;
            app.EdgessecondsEditField.Value = app.edges;
            app.offset = 0;
            expPath = app.experiment.metadata.path;
            powerPath = fullfile(expPath, 'powerData.mat');
            app.figSaveDir = powerPath;
            try
                load(powerPath, 'powerData')
                app.power = powerData;
            catch
                app.power = struct();
            end
            % app.LFPChannelButtonGroup.Visible = 'off';        %Commented
            % out while working on the h5 file interface
            app.trialTypes = [];
            app.outcomes = [];
            app.stimTypes = [];
            app.alignment = [];
            app.bpodEvent = false;
            app.toggle_bpod_upper('off');
            app.filteredSignal = [];
            app.currentChannel = 1;
            app.currentNeuron = [];
            app.PresetListBox.Items = {'All'};
            app.PresetListBox_2.Items = {'All'};
            app.topSelection = 'Raster';
            app.bottomSelection = 'PSTH';
            app.diffPlotOptions = false;
            if ~isfield(app.experiment.metadata, 'h5Path')
                app.cwtFileName = fullfile(uigetdir(pwd, 'Please select the location to save HDF5 containing CWT power:'), "cwt_power.h5");
                app.experiment.metadata.h5Path = app.cwtFileName;
            else
                app.cwtFileName = app.experiment.metadata.h5Path;
            end
            % app.cwtFileName = fullfile(expPath, "cwt_power.h5");
            app.minFR = 0;
            app.neuronIdx = 1;
            %   Eliminate dark mode panels
            app.TopPanel.BackgroundColor = [0.94,0.94,0.94];
            app.BottomPanel.BackgroundColor = [0.94,0.94,0.94];
            app.defaultTopSave = true;
            app.defaultBottomSave = true;            
            app.topSaveFormat = strcat('.', lower(app.FiletypeButtonGroup.SelectedObject.Text));
            app.bottomSaveFormat = strcat('.', lower(app.FiletypeButtonGroup_2.SelectedObject.Text));
            app.topSliderRange = [];
            app.bottomSliderRange = [];
            app.preset = PresetManager;
            app.presetUpper = PresetManager;
            app.presetLower = PresetManager;
            app.savedPresets.none = [];
            app.PresetListBox.Items = {'none'};
            app.PresetListBox.Value = 'none';
            app.PresetListBox_2.Items = {'none'};
            app.PresetListBox_2.Value = 'none';
            app.NeuronSetListBox.Items = {'none'};
            app.NeuronSetListBox.Value = 'none';
            app.NeuronSetListBox_2.Items = {'none'};
            app.NeuronSetListBox_2.Value = 'none';
            app.updatePop = true;

            app.UIFigure.Color = [0.1294 0.1294 0.1294];
            app.UIFigure.WindowState = 'maximized'
            for c = 1:numel(app.UIFigure.Children)
                if isprop(app.UIFigure.Children(c), 'BackgroundColor')
                    app.UIFigure.Children(c).BackgroundColor = [0.1294 0.1294 0.1294];
                end
                if isprop(app.UIFigure.Children(c), 'FontColor')
                    app.UIFigure.Children(c).FontColor = [1 1 1];
                end
            end

        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)

            value = app.SubjectDropDown.Value;
            app.currentSubject = value;
            if ~strcmp(string(value), "Select a subject")
                subSessions = arrayfun(@(x) strcmp(x.info.name, value), app.experiment.sessions);
                [x{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.experiment.sessions, 'uni', 0);
                sessionNames = x{2}(subSessions);
%                 sessionNames = cellfun(@(x) regexprep(x, '[()-]', '_'), sessionNames, 'uni', 0);
                sessionNames = [{'Select a session'}, sessionNames];
                app.SessionDropDown.Items = sessionNames;
                % app.LFPChannelButtonGroup.Visible = 'off';
            end
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            sessionName = regexprep(value, '[()-]', '_');
            if ~strcmp(string(value), "Select a session")
                [I{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.experiment.sessions, 'uni', 0);
                whichSession = arrayfun(@(x) strcmp(x, value), I{2});
                subSessions = arrayfun(@(x) strcmp(x.info.name, app.currentSubject), app.experiment.sessions);
                whichSession = find(whichSession&subSessions);
                app.switch_sessions(whichSession)
%                 app.currentObject = app.experiment.sessions(whichSession);
%                 % % This ugly line of code ensures you can click the scatter
%                 % % plot to select a neuron - the Child of DigraphPanel is
%                 % % the axes, whose first child is the scatter plot.
%                 % % (currently not available)
%                 % if isfield(app.TopPanel, 'Children')
%                 %     app.TopPanel.Children.Children(1).ButtonDownFcn = @app.scatterClickFcn;
%                 % end
%                 if ~isempty(app.currentObject.spikes)
%                     allFR = extractfield(app.currentObject.spikes, 'fr');
%                     app.goodNeurons = find(allFR >= app.minFR);
%                     neuronString = arrayfun(@num2str, app.goodNeurons, 'uni', 0);
%                 else
%                     allFR = [];
%                     app.goodNeurons = [];
%                     neuronString = [];
%                 end
%                 neuronString = [{'Select a neuron'}, neuronString];
%                 app.NeuronDropDown.Items = neuronString;
%                 alignments = fields(app.currentObject.timestamps.keys)';
%                     appAlignments = [{'Select an alignment', 'Bpod'}, alignments];
%                 app.AlignmentDropDown.Items = appAlignments;                
%                 app.AlignmentDropDown_2.Items = appAlignments;
% 
%                 availableTT = fields(app.currentObject.info.trialTypes)';
%                 availableTT = [{'All'}, availableTT];
%                 app.TrialTypesListBox.Items = availableTT;
%                 app.TrialTypesListBox_2.Items = availableTT;
%                 if isfield(app.currentObject.info, 'stimTypes')
%                     availableStimTypes = fields(app.currentObject.info.stimTypes)';
%                     availableStimTypes = [{'All'}, availableStimTypes];
%                 else
%                     availableStimTypes = {'None'};
%                 end
%                 app.StimTypesListBox.Items = availableStimTypes;
%                 app.StimTypesListBox_2.Items = availableStimTypes;   
%                 availableOutcomes = fields(app.currentObject.info.outcomes)';
%                 availableOutcomes = [{'All'}, availableOutcomes];
% %                 app.OutcomeDropDown.Items = availableOutcomes;
% 
%                 app.OutcomeListBox.Items = availableOutcomes;
%                 app.OutcomeListBox_2.Items = availableOutcomes;
%                 app.LowerFreqLimitEditField.Value = 6;
%                 app.UpperFreqLimitEditField.Value = 12;
%                 app.freqLimits = [6 12];
%                 app.filteredSignal = [];
%                 % Structure subfield layout: session -> alignment -> trialType -> outcome
% 
%                 if ~isfield(app.power, sessionName)
%                     app.power.(sessionName) = struct();       
%                 end
%                 app.currentSession = value;
%                 app.currentTrial = 1;
%                 app.maxTrials = app.currentObject.bpod.session.nTrials;
%                 app.numNeurons = numel(app.currentObject.spikes);
%                 app.show_button_group       
%                 try
%                     regionNames = extractfield(app.currentObject.spikes, 'region');
%                     goodRegionNames = cellfun(@(x) ~isempty(x), regionNames);
%                     uniqueRegions = unique(regionNames(goodRegionNames));
%                     app.RegionDropDown.Items = [{'All'}, uniqueRegions];
%                 catch
%                     app.RegionDropDown.Items = {'All'};
%                 end
%                 app.plot_current_type(app.TopPanel);
%                 app.plot_current_type(app.BottomPanel);
% %                 app.update_digraph
            end
        end

        % Button pushed function: PrevSessionButton
        function PrevSessionButtonPushed(app, event)
            [~, sessNames] = arrayfun(@(x) fileparts(x.info.path), app.experiment.sessions, 'uni', 0);
            subNames = arrayfun(@(x) x.info.name, app.experiment.sessions, 'uni', 0);
            thisSession = find(strcmp(sessNames, app.currentSession) & strcmp(subNames, app.currentSubject));
            if thisSession == 1
                prevSession = numel(app.experiment.sessions);
            else
                prevSession = thisSession - 1;
            end
            app.switch_sessions(prevSession)
        end

        % Button pushed function: NextSessionButton
        function NextSessionButtonPushed(app, event)
            [~, sessNames] = arrayfun(@(x) fileparts(x.info.path), app.experiment.sessions, 'uni', 0);
            subNames = arrayfun(@(x) x.info.name, app.experiment.sessions, 'uni', 0);
            thisSession = find(strcmp(sessNames, app.currentSession) & strcmp(subNames, app.currentSubject));
            if thisSession == numel(app.experiment.sessions)
                nextSession = 1;
            else
                nextSession = thisSession + 1;
            end
            app.switch_sessions(nextSession);
        end

        % Value changed function: NeuronDropDown
        function NeuronDropDownValueChanged(app, event)
            value = app.NeuronDropDown.Value;
            app.neuronIdx = find(app.goodNeurons == str2double(value));
            if ~strcmp(string(value), "Select a neuron")
                if ~isempty(app.currentNeuron)                
                    try
                    eval(strcat("app.Chan", num2str(app.currentObject.spikes(app.currentNeuron).channel), ".BackgroundColor = [.96 .96 .96];"))
                    catch
                    app.Chan1.BackgroundColor = [.96 .96 .96];
                    end
                end
                app.currentNeuron = str2double(value);
                try
                    spikeChannel = app.currentObject.spikes(app.currentNeuron).channel;
                catch
                    warning('No channel region found. Automatically putting 1 for quickndirty')
                    spikeChannel = 1;
                end
                eval(strcat("app.Chan", num2str(spikeChannel), ".BackgroundColor = 'g';"));
            end

            app.updatePop = false;
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
            app.updatePop = true;
        end

        % Button pushed function: NextNeuronButton
        function NextNeuronButtonPushed(app, event)
            app.neuronIdx = app.neuronIdx + 1;
            if app.neuronIdx > numel(app.goodNeurons)
                app.neuronIdx = 1;
            end
            if isempty(app.goodNeurons)
                return
            end
            app.currentNeuron = app.goodNeurons(app.neuronIdx);
            app.NeuronDropDown.Value = num2str(app.currentNeuron);
            d = uiprogressdlg(app.UIFigure, 'Title', "Changing neurons, Randall...", 'Indeterminate', 'on');
            drawnow
            app.updatePop = false;
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
            app.updatePop = true;
            close(d)
        end

        % Button pushed function: PrevNeuronButton
        function PrevNeuronButtonPushed(app, event)
            app.neuronIdx = app.neuronIdx - 1;
            if app.neuronIdx < 1
                app.neuronIdx = numel(app.goodNeurons);
            end
            if isempty(app.goodNeurons)
                return
            end
            app.currentNeuron = app.goodNeurons(app.neuronIdx);
            app.NeuronDropDown.Value = num2str(app.currentNeuron);
            d = uiprogressdlg(app.UIFigure, 'Title', "Changing neurons, Randall...", 'Indeterminate', 'on');
            drawnow
            app.updatePop = false;
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
            app.updatePop = true;
            close(d)
        end

        % Value changed function: MinimumFRSpinner
        function MinimumFRSpinnerValueChanged(app, event)
            app.minFR = app.MinimumFRSpinner.Value;
            app.find_good_neurons
        end

        % Value changed function: AlignmentDropDown
        function AlignmentDropDownValueChanged(app, event)
            value = app.AlignmentDropDown.Value;
            app.filteredSignal = [];
            if ~ismember(value, {'Select an alignment', 'Bpod'})
                app.bpodEvent = false;
                app.toggle_bpod_upper('off');
                app.alignment = value;
                app.presetUpper.event = value;
                app.presetUpper.bpod = false;
                app.show_button_group
                app.maxTrials = numel(app.currentObject.find_event('preset', app.presetUpper, 'trialized', true));
                app.plot_current_type(app.TopPanel)
                if ~app.diffPlotOptions
                    app.presetLower.event = value;
                    app.presetLower.bpod = false;
                    app.plot_current_type(app.BottomPanel)
                end
            end
            if strcmp(string(value), "Bpod")
                app.bpodEvent = true;
                app.alignment = app.BpodEventEditField.Value;
                app.presetUpper.event = app.BpodEventEditField.Value;
                app.presetUpper.bpod = true;
                app.toggle_bpod_upper('on');
                app.maxTrials = numel(app.currentObject.find_event('preset', app.presetUpper, 'trialized', true));
                app.plot_current_type(app.TopPanel);
                if ~app.diffPlotOptions
                    app.presetLower.event = app.BpodEventEditField.Value;
                    app.presetLower.bpod = true;
                    app.plot_current_type(app.BottomPanel);
                end
%                 app.update_digraph
            end
        end

        % Value changed function: AlignmentDropDown_2
        function AlignmentDropDown_2ValueChanged(app, event)
            value = app.AlignmentDropDown_2.Value;
            app.filteredSignal = [];
            if ~ismember(value, {'Select an alignment', 'Bpod'})
                % app.bpodEvent = false;
                app.toggle_bpod_lower('off');
                app.alignmentLower = value;
                app.presetLower.event = value;
                app.presetLower.bpod = false;
                app.show_button_group
                app.maxTrialsLower = numel(app.currentObject.find_event('preset', app.presetLower, 'trialized', true));
                app.plot_current_type(app.BottomPanel)
            end
            if strcmp(string(value), "Bpod")
                % app.bpodEvent = true;
                app.alignmentLower = app.BpodEventEditField.Value;
                app.presetLower.event = app.BpodEventEditField.Value;
                app.presetLower.bpod = true;
                app.toggle_bpod_lower('on');
                app.maxTrialsLower = numel(app.currentObject.find_event('preset', app.presetLower, 'trialized', true));
                app.plot_current_type(app.BottomPanel);
%                 app.update_digraph
            end
        end

        % Value changed function: BpodEventEditField
        function BpodEventEditFieldValueChanged(app, event)
            
            value = app.BpodEventEditField.Value;
            app.filteredSignal = [];
            app.alignment = value;
            app.presetUpper.event = value;
            % if app.sep
            app.presetLower.event = value;
            app.maxTrials = numel(app.currentObject.find_event('preset', app.presetUpper, 'trialized', true));
            app.show_button_group
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
%             app.update_digraph
        end

        % Value changed function: BpodEventEditField_2
        function BpodEventEditField_2ValueChanged(app, event)
           
            value = app.BpodEventEditField_2.Value;
            app.filteredSignal = [];
            app.alignmentLower = value;
            app.presetLower.event = value;
            app.maxTrials = numel(app.currentObject.find_event('preset', app.presetLower, 'trialized', true));
            app.show_button_group
            app.plot_current_type(app.BottomPanel);
%             app.update_digraph
        end

        % Value changed function: WithinStateEditField
        function WithinStateEditFieldValueChanged(app, event)
            app.withinState = app.WithinStateEditField.Value;
            app.presetUpper.withinState = app.withinState;
            app.presetLower.withinState = app.withinState;
            app.show_button_group
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
%             app.update_digraph
            
        end

        % Value changed function: WithinStateEditField_2
        function WithinStateEditField_2ValueChanged(app, event)
            value = app.WithinStateEditField_2.Value;
            app.presetLower.withinState = value;
            app.plot_current_type(app.BottomPanel);

        end

        % Value changed function: ExcludeByStateEditField
        function ExcludeByStateEditFieldValueChanged(app, event)
            app.excludeByState = app.ExcludeByStateEditField.Value;
            app.presetUpper.excludeEventsByState = app.excludeByState;
            app.presetLower.excludeEventsByState = app.excludeByState;
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            
        end

        % Value changed function: ExcludeByStateEditField_2
        function ExcludeByStateEditField_2ValueChanged(app, event)
            value = app.ExcludeByStateEditField_2.Value;
            app.presetLower.excludeEventsByState = value;
            app.plot_current_type(app.BottomPanel);

        end

        % Value changed function: PriorToStateEditField
        function PriorToStateEditFieldValueChanged(app, event)
            app.priorToState = app.PriorToStateEditField.Value;
            app.presetUpper.priorToState = app.priorToState;
            app.presetLower.priorToState = app.priorToState;
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            
        end

        % Value changed function: PriorToStateEditField_2
        function PriorToStateEditField_2ValueChanged(app, event)
            value = app.PriorToStateEditField_2.Value;
            app.presetLower.priorToState = app.priorToState;
            app.plot_current_type(app.BottomPanel);
        end

        % Value changed function: PriorToEventEditField
        function PriorToEventEditFieldValueChanged(app, event)
            app.priorToEvent = app.PriorToEventEditField.Value;
            app.presetUpper.priorToEvent = app.priorToEvent;
            app.presetLower.priorToEvent = app.priorToEvent;
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            
        end

        % Value changed function: PriorToEventEditField_2
        function PriorToEventEditField_2ValueChanged(app, event)
            value = app.PriorToEventEditField_2.Value;
            app.presetLower.priorToEvent = app.priorToEvent;
            app.plot_current_type(app.BottomPanel);
        end

        % Button pushed function: CalculateAllPowerButton
        function CalculateAllPowerButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, 'Title', "Calculating CWT power for all alignments, trial types, and outcomes", 'Indeterminate', 'on');
            drawnow
            savedFreqs = 0;
            %for sub = 1:numel(app.experiment.metadata.subjects)
             for sub = 1
                subName = app.experiment.metadata.subjects{sub}
                whichSessions = arrayfun(@(x) strcmp(x.info.name, subName), app.experiment.sessions);
                subSessions = app.experiment.sessions(whichSessions);
                %for sess = 1:numel(subSessions)
                 for sess = 1
                    tic
                    currentSess = subSessions(sess);
                    [~, sessName] = fileparts(currentSess.info.path);
                    sessName = regexprep(sessName, '[()-]', '_')

                    availableAlignments = fields(currentSess.timestamps.keys)';
                    availableTT = ['All', fields(currentSess.info.trialTypes)'];
                    availableOutcomes = ['All', fields(currentSess.info.outcomes)'];

                    % Structure subfield layout: session -> alignment -> trialType -> outcome
                    % Starts at 2 currently to ignore 'Off' timestamps
                    % (65528), should fix to not hard-code
                    for al = 2:numel(availableAlignments)
                        currentAlignment = availableAlignments{al};
                        % disp(["Current alignment: " currentAlignment])
                        try
                            [pwr, app.freqs] = currentSess.cwt_power('event', currentAlignment, 'edges', [-app.edges app.edges], 'calculatePhase', false, ...
                                'bpod', false, 'offset', app.offset, 'trialized', true);
                        catch
                            continue
                        end
                        eventTimes = currentSess.find_event('event', currentAlignment);
                        eventTrials = discretize(eventTimes, [currentSess.timestamps.trialStart, currentSess.info.samples]);
                        if ~savedFreqs
                            h5create(app.cwtFileName, '/frequencies', size(app.freqs), 'Datatype', 'single')
                            h5write(app.cwtFileName, '/frequencies', app.freqs)
                            savedFreqs = 1;
                        end

                        
                        for tt = 1:numel(availableTT)
                            currentTT = availableTT{tt};
                            for o = 1:numel(availableOutcomes)
                                currentOutcome = availableOutcomes{o};
                                goodTrials = currentSess.bpod.trial_intersection_BpodParser('trialType', currentTT, 'outcome', currentOutcome);
                                goodEvents = ismember(eventTrials, find(goodTrials));
                                if ~any(goodEvents)
                                    continue
                                end
                                goodPwr = cat(4, pwr{goodEvents});
                                averagedPwr = mean(goodPwr, 4);
                                h5String = strcat("/", fullfile(subName, sessName, currentAlignment, currentTT, currentOutcome));
                                h5String = regexprep(h5String, "\", "/");
                                h5create(app.cwtFileName, h5String, size(averagedPwr), 'Datatype', 'single')
                                h5write(app.cwtFileName, h5String, averagedPwr)
                            end
                        end
                    end
                    toc
                end
            end

            app.currentChannel = 1;
            close(d)
        end

        % Button pushed function: CWTPowerButton
        function CWTPowerButtonPushed(app, event)
            sessionName = regexprep(app.currentSession, '[()-]', '_');

            % Structure subfield layout: session -> alignment -> trialType -> outcome
            if isempty(app.trialTypes)
                trialTypesStruct = 'All';
            else
                trialTypesStruct = app.trialTypes;
            end
            if isempty(app.outcomes)
                outcomesStruct = 'All';
            else
                outcomesStruct = app.outcomes;
            end
            h5String = strcat("/", fullfile(app.currentSubject, app.currentSession, app.alignment, trialTypesStruct, outcomesStruct));
            h5String = regexprep(h5String, "\", "/");
            try
                h5read(app.cwtFileName, h5String)
            catch
                d = uiprogressdlg(app.UIFigure, 'Title', "Calculating CWT", 'Indeterminate', 'on');
                drawnow
                [pwr, app.freqs] = app.currentObject.cwt_power('event', app.alignment, 'edges', [-app.edges app.edges], 'calculatePhase', false, ...
                    'bpod', app.bpodEvent, 'withinState', app.withinState, 'offset', app.offset, 'trialType', app.trialTypes, 'outcome', app.outcomes, 'averaged', true);
                pwr = cat(3, pwr{:});
                try
                    h5create(app.cwtFileName, '/frequencies', size(app.freqs), 'Datatype', 'single')
                    h5write(app.cwtFileName, '/frequencies', app.freqs)
                catch
                end
                h5create(app.cwtFileName, h5String, size(pwr), 'Datatype', 'single')
                h5write(app.cwtFileName, h5String, pwr)
                close(d)
                app.LFPChannelButtonGroup.Visible = 'on';
                app.currentChannel = 1;
            end
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
        end

        % Selection changed function: LFPChannelButtonGroup
        function LFPChannelButtonGroupSelectionChanged(app, event)
            selectedButton = app.LFPChannelButtonGroup.SelectedObject;
            app.currentChannel = str2double(selectedButton.Text);
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
        

        end

        % Selection changed function: TopPanelButtonGroup
        function TopPanelButtonGroupSelectionChanged(app, event)
            app.topSelection = app.TopPanelButtonGroup.SelectedObject.Text;
            app.plot_current_type(app.TopPanel)
        end

        % Selection changed function: BottomPanelButtonGroup
        function BottomPanelButtonGroupSelectionChanged(app, event)
            app.bottomSelection = app.BottomPanelButtonGroup.SelectedObject.Text;
            app.plot_current_type(app.BottomPanel)

        end

        % Value changed function: TrialTypesListBox
        function TrialTypesListBoxValueChanged(app, event)
            value = app.TrialTypesListBox.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.trialTypes))
                app.trialTypesUpper = value;
                app.presetUpper.trialType = value;
            else
                app.trialTypesUpper = [];
                app.presetUpper.trialType = [];
            end                
            app.show_button_group
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions
                app.trialTypesLower = app.trialTypesUpper;
                app.presetLower.copy(app.presetUpper);
                app.plot_current_type(app.BottomPanel);
            end
        end

        % Value changed function: TrialTypesListBox_2
        function TrialTypesListBox_2ValueChanged(app, event)
            value = app.TrialTypesListBox_2.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.trialTypes))
                app.trialTypesLower = value;
                app.presetLower.trialType = value;
            else
                app.trialTypesLower = [];
                app.presetLower.trialType = [];
            end                
            app.show_button_group
            app.plot_current_type(app.BottomPanel)

        end

        % Value changed function: OutcomeListBox
        function OutcomeListBoxValueChanged(app, event)
            value = app.OutcomeListBox.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.outcomes))
                app.outcomesUpper = value;
                app.presetUpper.outcome = value;
            else
                app.outcomesUpper = [];
                app.presetUpper.outcome = [];
            end
            app.show_button_group
            app.plot_current_type(app.TopPanel);

            if ~app.diffPlotOptions
                app.outcomesLower = app.outcomesUpper;
                app.presetLower.copy(app.presetUpper);
                app.plot_current_type(app.BottomPanel);
            end
        end

        % Value changed function: OutcomeListBox_2
        function OutcomeListBox_2ValueChanged(app, event)
            value = app.OutcomeListBox_2.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.outcomes))
                app.outcomesLower = value;
                app.presetLower.outcome = value;
            else
                app.outcomesLower = [];
                app.presetLower.outcome = [];
            end
            app.show_button_group
            app.plot_current_type(app.BottomPanel);
            
        end

        % Value changed function: StimTypesListBox
        function StimTypesListBoxValueChanged(app, event)
            value = app.StimTypesListBox.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.stimTypes))
                app.stimTypesUpper = value;
                app.presetUpper.stimType = value;
            else
                app.stimTypesUpper = [];
                app.presetUpper.stimType = [];
            end                
            app.show_button_group
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions
                app.stimTypesLower = app.stimTypesUpper;
                app.presetLower.copy(app.presetUpper);
                app.plot_current_type(app.BottomPanel);
            end
        end

        % Value changed function: StimTypesListBox_2
        function StimTypesListBox_2ValueChanged(app, event)
            value = app.StimTypesListBox_2.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.stimTypes))
                app.stimTypesLower = value;
                app.presetLower.stimType = value;
            else
                app.stimTypesLower = [];
                app.presetLower.stimType = [];
            end                
            app.show_button_group
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: SeparatePlotOptionsCheckBox
        function SeparatePlotOptionsCheckBoxValueChanged(app, event)
            app.diffPlotOptions = app.SeparatePlotOptionsCheckBox.Value;
            if app.diffPlotOptions
                app.presetLower.firstEvent = app.FirstEventCheckBox_2.Value;
                app.presetLower.lastEvent = app.LastEventCheckBox_2.Value;
                app.OutcomeListBox_2.Enable = true;
                app.TrialTypesListBox_2.Enable = true;   
                app.StimTypesListBox_2.Enable = true;
                app.PresetListBox_2.Enable = true;
                app.AddBottomPresetButton.Enable = true;
                app.RemoveBottomPresetButton.Enable = true;
                % app.NeuronDropDown_2.Enable = true;
                app.AlignmentDropDown_2.Enable = true;
                AlignmentDropDown_2ValueChanged(app, true)
                outcomeSelection = app.OutcomeListBox_2.Value;
                app.filteredSignal = [];
                if ismember(outcomeSelection, fields(app.currentObject.info.outcomes))
                    app.outcomesLower = outcomeSelection;
                    app.presetLower.outcome = app.outcomesLower;
                else
                    app.outcomesLower = [];
                    app.presetLower.outcome = [];
                end

                trialTypeSelection = app.TrialTypesListBox_2.Value;
                app.filteredSignal = [];
                if ismember(trialTypeSelection, fields(app.currentObject.info.trialTypes))
                    app.trialTypesLower = trialTypeSelection;
                    app.presetLower.trialType = trialTypeSelection;
                else
                    app.trialTypesLower = [];
                    app.presetLower.trialType = [];
                end        

                stimTypeSelection = app.StimTypesListBox_2.Value;
                app.filteredSignal = [];
                if isfield(app.currentObject.info, 'stimTypes') && ismember(stimTypeSelection, fields(app.currentObject.info.stimTypes))
                    app.stimTypesLower = stimTypeSelection;
                    app.presetLower.stimType = stimTypeSelection;
                else
                    app.stimTypesLower = [];
                    app.presetLower.stimType = [];
                end
                app.FirstEventCheckBox_2.Visible = "on";
                app.LastEventCheckBox_2.Visible = "on";
                presetSelection = app.PresetListBox_2.Value;
                app.filteredSignal = [];

                app.trialSetsLower = presetSelection;
            else
                app.presetLower.copy(app.presetUpper);
                app.OutcomeListBox_2.Enable = false;
                app.TrialTypesListBox_2.Enable = false;
                app.StimTypesListBox_2.Enable = false;
                app.PresetListBox_2.Enable = false;
                app.outcomesLower = app.outcomesUpper;
                app.trialTypesLower = app.trialTypesUpper;
                app.AddBottomPresetButton.Enable = false;
                app.RemoveBottomPresetButton.Enable = false;
                % app.NeuronDropDown_2.Enable = false;
                app.AlignmentDropDown_2.Enable = false;
                app.FirstEventCheckBox_2.Visible = "off";
                app.LastEventCheckBox_2.Visible = "off";
            end

            app.plot_current_type(app.BottomPanel)

        end

        % Value changed function: EdgessecondsEditField
        function EdgessecondsEditFieldValueChanged(app, event)
            app.edges = app.EdgessecondsEditField.Value;
            app.presetUpper.edges = [-app.edges, app.edges];
            app.presetLower.edges = [-app.edges, app.edges];
            app.filteredSignal = [];
            % app.LFPChannelButtonGroup.Visible = 'off';
            app.reset_power_struct
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            
        end

        % Value changed function: OffsetsecondsEditField
        function OffsetsecondsEditFieldValueChanged(app, event)
            app.offset = app.OffsetsecondsEditField.Value;
            app.presetUpper.offset = app.offset;
            app.presetLower.offset = app.offset;
            app.filteredSignal = [];
            % app.LFPChannelButtonGroup.Visible = 'off';
            app.reset_power_struct   
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);

        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)
            app.currentTrial = app.currentTrial - 1;
            if app.currentTrial == 0
                app.currentTrial = app.maxTrials;
            end
            d = uiprogressdlg(app.UIFigure, 'Title', "Changing trials, Randall...", 'Indeterminate', 'on');
            drawnow
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            close(d)
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            app.currentTrial = app.currentTrial + 1;
            if app.currentTrial > app.maxTrials
                app.currentTrial = 1;
            end

            d = uiprogressdlg(app.UIFigure, 'Title', "Changing trials, Randall...", 'Indeterminate', 'on');
            drawnow
            app.plot_current_type(app.TopPanel);
            app.plot_current_type(app.BottomPanel);
            close(d)
        end

        % Value changed function: LowerFreqLimitEditField
        function LowerFreqLimitEditFieldValueChanged(app, event)
            app.freqLimits(1) = app.LowerFreqLimitEditField.Value;
            app.filteredSignal = [];
        end

        % Value changed function: UpperFreqLimitEditField
        function UpperFreqLimitEditFieldValueChanged(app, event)
            app.freqLimits(2) = app.UpperFreqLimitEditField.Value;
            app.filteredSignal = [];
        end

        % Button pushed function: SavetopplotButton
        function SavetopplotButtonPushed(app, event)
            app.save_figure(app.TopPanel, app.defaultTopSave)
        end

        % Button pushed function: SavebottomplotButton
        function SavebottomplotButtonPushed(app, event)
            app.save_figure(app.BottomPanel, app.defaultBottomSave)
        end

        % Button pushed function: PlotCurrentSettingsButton
        function PlotCurrentSettingsButtonPushed(app, event)
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: AutomaticNamesSwitch
        function AutomaticNamesSwitchValueChanged(app, event)
            app.defaultTopSave = ~app.defaultTopSave;
        end

        % Value changed function: AutomaticNamesSwitch_2
        function AutomaticNamesSwitch_2ValueChanged(app, event)
            app.defaultBottomSave = ~app.defaultBottomSave;
        end

        % Selection changed function: FiletypeButtonGroup
        function FiletypeButtonGroupSelectionChanged(app, event)
            app.topSaveFormat = strcat('.', lower(app.FiletypeButtonGroup.SelectedObject.Text));
            
        end

        % Selection changed function: FiletypeButtonGroup_2
        function FiletypeButtonGroup_2SelectionChanged(app, event)
            app.bottomSaveFormat = strcat('.', lower(app.FiletypeButtonGroup_2.SelectedObject.Text));
            
        end

        % Value changed function: PresetListBox
        function PresetListBoxValueChanged(app, event)
            currentPreset = app.PresetListBox.Value;
            if strcmp(currentPreset, 'none')
                return
            end
            app.presetUpper.copy(app.savedPresets.(currentPreset));
            app.assign_preset_values(app.presetUpper)
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions
                app.presetLower.copy(app.presetUpper);
                app.plot_current_type(app.BottomPanel)
            end
        end

        % Value changed function: PresetListBox_2
        function PresetListBox_2ValueChanged(app, event)
            currentPreset = app.PresetListBox_2.Value;
            if strcmp(currentPreset, 'none')
                return
            end
            app.presetLower.copy(app.savedPresets.(currentPreset));
            app.assign_preset_values(app.presetLower)
            app.plot_current_type(app.BottomPanel)
        end

        % Button pushed function: AddTopPresetButton
        function AddTopPresetButtonPushed(app, event)
            app.add_preset(app.TopPanel)
        end

        % Button pushed function: RemoveTopPresetButton
        function RemoveTopPresetButtonPushed(app, event)
            app.remove_preset(app.PresetListBox.Value);
        end

        % Button pushed function: AddBottomPresetButton
        function AddBottomPresetButtonPushed(app, event)
            app.add_preset(app.BottomPanel)
        end

        % Button pushed function: RemoveBottomPresetButton
        function RemoveBottomPresetButtonPushed(app, event)
            app.remove_preset(app.PresetListBox_2.Value)
        end

        % Button pushed function: LoadNewSessionsButton
        function LoadNewSessionsButtonPushed(app, event)
            app.startupFcn
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            value = app.RegionDropDown.Value;
            if strcmp(value, 'All')
                app.currentRegion = [];
            else
                app.currentRegion = value;
            end
            app.find_good_neurons
        end

        % Button pushed function: AddTopNeuronSetButton_2
        function AddTopNeuronSetButton_2Pushed(app, event)
            app.add_neuronSet(app.TopPanel)

        end

        % Button pushed function: RemoveTopNeuronSetButton_2
        function RemoveTopNeuronSetButton_2Pushed(app, event)
            app.remove_neuronSet(app.TopPanel)

        end

        % Button pushed function: AddBottomNeuronSetButton_2
        function AddBottomNeuronSetButton_2Pushed(app, event)
            app.add_neuronSet(app.BottomPanel)

        end

        % Button pushed function: RemoveBottomNeuronSetButton_2
        function RemoveBottomNeuronSetButton_2Pushed(app, event)
            app.remove_neuronSet(app.BottomPanel)

        end

        % Value changed function: NeuronSetListBox
        function NeuronSetListBoxValueChanged(app, event)
            value = app.NeuronSetListBox.Value;
                        currentPreset = app.NeuronSetListBox.Value;
            if strcmp(currentPreset, 'none')
                return
            end
            app.presetUpper.copy(app.savedPresets.(currentPreset));
            app.assign_preset_values(app.presetUpper)
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions
                app.presetLower.copy(app.presetUpper);
                app.plot_current_type(app.BottomPanel)
            end
        end

        % Value changed function: NeuronSetListBox_2
        function NeuronSetListBox_2ValueChanged(app, event)
            currentPreset = app.PresetListBox_2.Value;
            if strcmp(currentPreset, 'none')
                return
            end
            app.presetLower.copy(app.savedPresets.(currentPreset));
            app.assign_preset_values(app.presetLower)
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: SankeyInEditField
        function SankeyInEditFieldValueChanged(app, event)
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: SankeyOutEditField
        function SankeyOutEditFieldValueChanged(app, event)
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            app.showTopLegend = app.ShowLegendCheckBox.Value;
            app.update_legends
        end

        % Value changed function: ShowLegendCheckBox_2
        function ShowLegendCheckBox_2ValueChanged(app, event)
            app.showBottomLegend = app.ShowLegendCheckBox_2.Value;
            app.update_legends
        end

        % Value changed function: TopSlider
        function TopSliderValueChanged(app, event)
            value = app.TopSlider.Value;
            msRange = [-app.edges, app.edges] * 1000;
            msEdges = msRange(1):app.binWidth:msRange(2);
            sortRange = value/100;
            app.topSliderRange = round(sortRange * numel(msEdges)) - (app.edges * 1000/app.binWidth);
            app.plot_current_type(app.TopPanel)  
        end

        % Value changed function: SortPopTopCheckbox
        function SortPopTopCheckboxValueChanged(app, event)
            app.sortPopTop = app.SortPopTopCheckbox.Value;
            app.plot_current_type(app.TopPanel)
        end

        % Value changed function: SortPopBottomCheckbox
        function SortPopBottomCheckboxValueChanged(app, event)
            app.sortPopBottom = app.SortPopBottomCheckbox.Value;
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: BottomSlider
        function BottomSliderValueChanged(app, event)
            value = app.BottomSlider.Value;
            msRange = [-app.edges, app.edges] * 1000;
            msEdges = msRange(1):app.binWidth:msRange(2);
            sortRange = value/100;
            app.bottomSliderRange = round(sortRange * numel(msEdges)) - (app.edges * 1000/app.binWidth);
            app.plot_current_type(app.BottomPanel)  
        end

        % Value changed function: FirstEventCheckBox
        function FirstEventCheckBoxValueChanged(app, event)
            app.presetUpper.firstEvent = app.FirstEventCheckBox.Value;
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions            
                app.presetLower.firstEvent = app.FirstEventCheckBox.Value;
                app.plot_current_type(app.BottomPanel)
            end
        end

        % Value changed function: LastEventCheckBox
        function LastEventCheckBoxValueChanged(app, event)
            app.presetUpper.lastEvent = app.LastEventCheckBox.Value;
            app.plot_current_type(app.TopPanel)
            if ~app.diffPlotOptions
                app.presetLower.lastEvent = app.LastEventCheckBox.Value;
                app.plot_current_type(app.BottomPanel)
            end
        end

        % Value changed function: FirstEventCheckBox_2
        function FirstEventCheckBox_2ValueChanged(app, event)
            app.presetLower.firstEvent = app.FirstEventCheckBox_2.Value;
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: LastEventCheckBox_2
        function LastEventCheckBox_2ValueChanged(app, event)
            app.presetLower.lastEvent = app.LastEventCheckBox_2.Value;
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: CLimSlider
        function CLimSliderValueChanged(app, event)
            value = app.CLimSlider.Value;
            app.presetUpper.cLim = value;
            app.plot_current_type(app.TopPanel)
            if app.matchCLim
                app.presetLower.cLim = value;
                app.plot_current_type(app.BottomPanel)
            end
        end

        % Value changed function: CLimSlider_2
        function CLimSlider_2ValueChanged(app, event)
            value = app.CLimSlider_2.Value;
            app.presetLower.cLim = value;
            app.plot_current_type(app.BottomPanel);
        end

        % Value changed function: MatchcLimCheckBox
        function MatchcLimCheckBoxValueChanged(app, event)
            value = app.MatchcLimCheckBox.Value;
            app.matchCLim = value;
            if value
                app.presetLower.cLim = app.presetUpper.cLim;
                app.CLimSlider_2.Visible = false;
            else
                app.presetLower.cLim = app.CLimSlider_2.Value;
                app.CLimSlider_2.Visible = true;
            end
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
        end

        % Value changed function: SortBottomByTopCheckBox
        function SortBottomByTopCheckBoxValueChanged(app, event)
            value = app.SortBottomByTopCheckBox.Value;
            app.shareSorting = value;
            app.SortPopBottomCheckbox.Visible = value;
            app.plot_current_type(app.TopPanel)
            app.plot_current_type(app.BottomPanel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.NeuronDropDownLabel = uilabel(app.UIFigure);
            app.NeuronDropDownLabel.Text = 'Neuron';
            app.NeuronDropDown = uidropdown(app.UIFigure);
            app.NeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuronDropDownValueChanged, true);
            app.BottomPanel = uipanel(app.UIFigure);
            app.BottomPanel.Title = 'Bottom Panel';
            app.TopPanel = uipanel(app.UIFigure);
            app.TopPanel.Title = 'Top Panel';
            app.AlignmentDropDownLabel = uilabel(app.UIFigure);
            app.AlignmentDropDownLabel.Text = 'Alignment';
            app.AlignmentDropDown = uidropdown(app.UIFigure);
            app.AlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmentDropDownValueChanged, true);
            app.TrialTypesListBoxLabel = uilabel(app.UIFigure);
            app.TrialTypesListBoxLabel.Text = 'TrialTypes';
            app.TrialTypesListBox = uilistbox(app.UIFigure);
            app.TrialTypesListBox.Multiselect = 'on';
            app.TrialTypesListBox.ValueChangedFcn = createCallbackFcn(app, @TrialTypesListBoxValueChanged, true);
            app.TrialTypesListBox.Value = {'Item 1'};
            app.LFPChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.LFPChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LFPChannelButtonGroupSelectionChanged, true);
            app.LFPChannelButtonGroup.Title = 'LFP Channel';
            app.Chan1 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan1.Text = '1';
            app.Chan1.Value = true;
            app.Chan2 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan2.Text = '2';
            app.Chan3 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan3.Text = '3';
            app.Chan4 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan4.Text = '4';
            app.Chan5 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan5.Text = '5';
            app.Chan6 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan6.Text = '6';
            app.Chan7 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan7.Text = '7';
            app.Chan8 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan8.Text = '8';
            app.Chan9 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan9.Text = '9';
            app.Chan10 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan10.Text = '10';
            app.Chan11 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan11.Text = '11';
            app.Chan12 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan12.Text = '12';
            app.Chan13 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan13.Text = '13';
            app.Chan14 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan14.Text = '14';
            app.Chan15 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan15.Text = '15';
            app.Chan16 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan16.Text = '16';
            app.Chan17 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan17.Text = '17';
            app.Chan18 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan18.Text = '18';
            app.Chan19 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan19.Text = '19';
            app.Chan20 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan20.Text = '20';
            app.Chan21 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan21.Text = '21';
            app.Chan22 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan22.Text = '22';
            app.Chan23 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan23.Text = '23';
            app.Chan24 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan24.Text = '24';
            app.Chan25 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan25.Text = '25';
            app.Chan26 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan26.Text = '26';
            app.Chan27 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan27.Text = '27';
            app.Chan28 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan28.Text = '28';
            app.Chan29 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan29.Text = '29';
            app.Chan30 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan30.Text = '30';
            app.Chan31 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan31.Text = '31';
            app.Chan32 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan32.Text = '32';
            app.EdgessecondsEditFieldLabel = uilabel(app.UIFigure);
            app.EdgessecondsEditFieldLabel.Text = 'Edges (seconds)';
            app.EdgessecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgessecondsEditField.ValueChangedFcn = createCallbackFcn(app, @EdgessecondsEditFieldValueChanged, true);
            app.OffsetsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetsecondsEditFieldLabel.Text = 'Offset (seconds)';
            app.OffsetsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetsecondsEditFieldValueChanged, true);
            app.BpodEventEditFieldLabel = uilabel(app.UIFigure);
            app.BpodEventEditFieldLabel.Text = 'Bpod Event';
            app.BpodEventEditField = uieditfield(app.UIFigure, 'text');
            app.BpodEventEditField.ValueChangedFcn = createCallbackFcn(app, @BpodEventEditFieldValueChanged, true);
            app.NextTrialButton = uibutton(app.UIFigure, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = 'Next Trial';
            app.PreviousTrialButton = uibutton(app.UIFigure, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Text = 'Previous Trial';
            app.LowerFreqLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerFreqLimitEditFieldLabel.Text = 'Lower Freq Limit';
            app.LowerFreqLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerFreqLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFreqLimitEditFieldValueChanged, true);
            app.UpperFreqLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperFreqLimitEditFieldLabel.Text = 'Upper Freq Limit';
            app.UpperFreqLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperFreqLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFreqLimitEditFieldValueChanged, true);
            app.WithinStateEditFieldLabel = uilabel(app.UIFigure);
            app.WithinStateEditFieldLabel.Text = 'Within State';
            app.WithinStateEditField = uieditfield(app.UIFigure, 'text');
            app.WithinStateEditField.ValueChangedFcn = createCallbackFcn(app, @WithinStateEditFieldValueChanged, true);
            app.BottomPanelButtonGroup = uibuttongroup(app.UIFigure);
            app.BottomPanelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BottomPanelButtonGroupSelectionChanged, true);
            app.BottomPanelButtonGroup.Title = 'Bottom Panel';
            app.RasterButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.RasterButton_Bottom.Text = 'Raster';
            app.PSTHButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.PSTHButton_Bottom.Text = 'PSTH';
            app.PSTHButton_Bottom.Value = true;
            app.PowerButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.PowerButton_Bottom.Text = 'Power';
            app.SpikePhaseButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.SpikePhaseButton_Bottom.Text = 'Spike Phase';
            app.DigraphButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.DigraphButton_Bottom.Text = 'Digraph';
            app.PerformanceButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.PerformanceButton_Bottom.Text = 'Performance';
            app.PopulationButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.PopulationButton_Bottom.Text = 'Population';
            app.SankeyButton_Bottom = uitogglebutton(app.BottomPanelButtonGroup);
            app.SankeyButton_Bottom.Text = 'Sankey';
            app.PopulationButton_Bottom_2 = uitogglebutton(app.BottomPanelButtonGroup);
            app.PopulationButton_Bottom_2.Text = 'LabGym';
            app.DigraphButton_Bottom_2 = uitogglebutton(app.BottomPanelButtonGroup);
            app.DigraphButton_Bottom_2.Text = 'Position';
            app.TopPanelButtonGroup = uibuttongroup(app.UIFigure);
            app.TopPanelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TopPanelButtonGroupSelectionChanged, true);
            app.TopPanelButtonGroup.Title = 'Top Panel';
            app.RasterButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.RasterButton_Top.Text = 'Raster';
            app.RasterButton_Top.Value = true;
            app.PSTHButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.PSTHButton_Top.Text = 'PSTH';
            app.PowerButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.PowerButton_Top.Text = 'Power';
            app.SpikePhaseButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.SpikePhaseButton_Top.Text = 'Spike Phase';
            app.DigraphButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.DigraphButton_Top.Text = 'Digraph';
            app.PerformanceButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.PerformanceButton_Top.Text = 'Performance';
            app.PopulationButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.PopulationButton_Top.Text = 'Population';
            app.SankeyButton_Top = uitogglebutton(app.TopPanelButtonGroup);
            app.SankeyButton_Top.Text = 'Sankey';
            app.PopulationButton_Top_2 = uitogglebutton(app.TopPanelButtonGroup);
            app.PopulationButton_Top_2.Text = 'LabGym';
            app.DigraphButton_Top_2 = uitogglebutton(app.TopPanelButtonGroup);
            app.DigraphButton_Top_2.Text = 'Position';
            app.LFPChannelButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.LFPChannelButtonGroup_2.Title = 'LFP Channel';
            app.Chan1_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan1_2.Text = '1';
            app.Chan1_2.Value = true;
            app.Chan2_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan2_2.Text = '2';
            app.Chan3_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan3_2.Text = '3';
            app.Chan4_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan4_2.Text = '4';
            app.Chan5_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan5_2.Text = '5';
            app.Chan6_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan6_2.Text = '6';
            app.Chan7_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan7_2.Text = '7';
            app.Chan8_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan8_2.Text = '8';
            app.Chan9_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan9_2.Text = '9';
            app.Chan10_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan10_2.Text = '10';
            app.Chan11_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan11_2.Text = '11';
            app.Chan12_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan12_2.Text = '12';
            app.Chan13_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan13_2.Text = '13';
            app.Chan14_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan14_2.Text = '14';
            app.Chan15_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan15_2.Text = '15';
            app.Chan16_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan16_2.Text = '16';
            app.Chan17_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan17_2.Text = '17';
            app.Chan18_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan18_2.Text = '18';
            app.Chan19_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan19_2.Text = '19';
            app.Chan20_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan20_2.Text = '20';
            app.Chan21_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan21_2.Text = '21';
            app.Chan22_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan22_2.Text = '22';
            app.Chan23_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan23_2.Text = '23';
            app.Chan24_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan24_2.Text = '24';
            app.Chan25_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan25_2.Text = '25';
            app.Chan26_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan26_2.Text = '26';
            app.Chan27_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan27_2.Text = '27';
            app.Chan28_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan28_2.Text = '28';
            app.Chan29_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan29_2.Text = '29';
            app.Chan30_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan30_2.Text = '30';
            app.Chan31_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan31_2.Text = '31';
            app.Chan32_2 = uitogglebutton(app.LFPChannelButtonGroup_2);
            app.Chan32_2.Text = '32';
            app.TrialTypesListBox_2Label = uilabel(app.UIFigure);
            app.TrialTypesListBox_2Label.Text = 'TrialTypes';
            app.TrialTypesListBox_2 = uilistbox(app.UIFigure);
            app.TrialTypesListBox_2.Multiselect = 'on';
            app.TrialTypesListBox_2.ValueChangedFcn = createCallbackFcn(app, @TrialTypesListBox_2ValueChanged, true);
            app.TrialTypesListBox_2.Enable = 'off';
            app.TrialTypesListBox_2.Value = {'Item 1'};
            app.SeparatePlotOptionsCheckBox = uicheckbox(app.UIFigure);
            app.SeparatePlotOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeparatePlotOptionsCheckBoxValueChanged, true);
            app.SeparatePlotOptionsCheckBox.Text = 'Separate Plot Options';
            app.NeuronDropDown_2Label = uilabel(app.UIFigure);
            app.NeuronDropDown_2Label.Enable = 'off';
            app.NeuronDropDown_2Label.Text = 'Neuron';
            app.NeuronDropDown_2 = uidropdown(app.UIFigure);
            app.NeuronDropDown_2.Enable = 'off';
            app.AlignmentDropDown_2Label = uilabel(app.UIFigure);
            app.AlignmentDropDown_2Label.Enable = 'off';
            app.AlignmentDropDown_2Label.Text = 'Alignment';
            app.AlignmentDropDown_2 = uidropdown(app.UIFigure);
            app.AlignmentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AlignmentDropDown_2ValueChanged, true);
            app.AlignmentDropDown_2.Enable = 'off';
            app.BottomPanelOptionsLabel = uilabel(app.UIFigure);
            app.BottomPanelOptionsLabel.Enable = 'off';
            app.BottomPanelOptionsLabel.Text = 'Bottom Panel Options';
            app.OutcomeListBoxLabel = uilabel(app.UIFigure);
            app.OutcomeListBoxLabel.Text = 'Outcome';
            app.OutcomeListBox = uilistbox(app.UIFigure);
            app.OutcomeListBox.Multiselect = 'on';
            app.OutcomeListBox.ValueChangedFcn = createCallbackFcn(app, @OutcomeListBoxValueChanged, true);
            app.OutcomeListBox.Value = {'Item 1'};
            app.OutcomeListBox_2Label = uilabel(app.UIFigure);
            app.OutcomeListBox_2Label.Text = 'Outcome';
            app.OutcomeListBox_2 = uilistbox(app.UIFigure);
            app.OutcomeListBox_2.Multiselect = 'on';
            app.OutcomeListBox_2.ValueChangedFcn = createCallbackFcn(app, @OutcomeListBox_2ValueChanged, true);
            app.OutcomeListBox_2.Enable = 'off';
            app.OutcomeListBox_2.Value = {'Item 1'};
            app.PrevNeuronButton = uibutton(app.UIFigure, 'push');
            app.PrevNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @PrevNeuronButtonPushed, true);
            app.PrevNeuronButton.Text = 'Prev Neuron';
            app.NextNeuronButton = uibutton(app.UIFigure, 'push');
            app.NextNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @NextNeuronButtonPushed, true);
            app.NextNeuronButton.Text = 'Next Neuron';
            app.SavetopplotButton = uibutton(app.UIFigure, 'push');
            app.SavetopplotButton.ButtonPushedFcn = createCallbackFcn(app, @SavetopplotButtonPushed, true);
            app.SavetopplotButton.Text = 'Save top plot';
            app.SavebottomplotButton = uibutton(app.UIFigure, 'push');
            app.SavebottomplotButton.ButtonPushedFcn = createCallbackFcn(app, @SavebottomplotButtonPushed, true);
            app.SavebottomplotButton.Text = 'Save bottom plot';
            app.MinimumFRSpinnerLabel = uilabel(app.UIFigure);
            app.MinimumFRSpinnerLabel.Text = 'Minimum FR';
            app.MinimumFRSpinner = uispinner(app.UIFigure);
            app.MinimumFRSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumFRSpinnerValueChanged, true);
            app.ExcludeByStateEditFieldLabel = uilabel(app.UIFigure);
            app.ExcludeByStateEditFieldLabel.Text = 'Exclude By State';
            app.ExcludeByStateEditField = uieditfield(app.UIFigure, 'text');
            app.ExcludeByStateEditField.ValueChangedFcn = createCallbackFcn(app, @ExcludeByStateEditFieldValueChanged, true);
            app.PriorToStateEditFieldLabel = uilabel(app.UIFigure);
            app.PriorToStateEditFieldLabel.Text = 'Prior To State';
            app.PriorToStateEditField = uieditfield(app.UIFigure, 'text');
            app.PriorToStateEditField.ValueChangedFcn = createCallbackFcn(app, @PriorToStateEditFieldValueChanged, true);
            app.PriorToEventEditFieldLabel = uilabel(app.UIFigure);
            app.PriorToEventEditFieldLabel.Text = 'Prior To Event';
            app.PriorToEventEditField = uieditfield(app.UIFigure, 'text');
            app.PriorToEventEditField.ValueChangedFcn = createCallbackFcn(app, @PriorToEventEditFieldValueChanged, true);
            app.AutomaticNamesSwitch_2Label = uilabel(app.UIFigure);
            app.AutomaticNamesSwitch_2Label.Text = 'Automatic Names';
            app.AutomaticNamesSwitchLabel = uilabel(app.UIFigure);
            app.AutomaticNamesSwitchLabel.Text = 'Automatic Names';
            app.AutomaticNamesSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.AutomaticNamesSwitch_2.ValueChangedFcn = createCallbackFcn(app, @AutomaticNamesSwitch_2ValueChanged, true);
            app.AutomaticNamesSwitch_2.Value = 'On';
            app.AutomaticNamesSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutomaticNamesSwitch.ValueChangedFcn = createCallbackFcn(app, @AutomaticNamesSwitchValueChanged, true);
            app.AutomaticNamesSwitch.Value = 'On';
            app.FiletypeButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.FiletypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @FiletypeButtonGroup_2SelectionChanged, true);
            app.FiletypeButtonGroup_2.Title = 'File type';
            app.FIGButton_2 = uiradiobutton(app.FiletypeButtonGroup_2);
            app.FIGButton_2.Text = 'FIG';
            app.FIGButton_2.Value = true;
            app.PNGButton_2 = uiradiobutton(app.FiletypeButtonGroup_2);
            app.PNGButton_2.Text = 'PNG';
            app.SVGButton_2 = uiradiobutton(app.FiletypeButtonGroup_2);
            app.SVGButton_2.Text = 'SVG';
            app.FiletypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FiletypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiletypeButtonGroupSelectionChanged, true);
            app.FiletypeButtonGroup.Title = 'File type';
            app.FIGButton = uiradiobutton(app.FiletypeButtonGroup);
            app.FIGButton.Text = 'FIG';
            app.FIGButton.Value = true;
            app.PNGButton = uiradiobutton(app.FiletypeButtonGroup);
            app.PNGButton.Text = 'PNG';
            app.SVGButton = uiradiobutton(app.FiletypeButtonGroup);
            app.SVGButton.Text = 'SVG';
            app.PlotCurrentSettingsButton = uibutton(app.UIFigure, 'push');
            app.PlotCurrentSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentSettingsButtonPushed, true);
            app.PlotCurrentSettingsButton.Text = 'Plot Current Settings';
            app.CalculateAllPowerButton = uibutton(app.UIFigure, 'push');
            app.CalculateAllPowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllPowerButtonPushed, true);
            app.CalculateAllPowerButton.Text = 'Calculate All Power';
            app.CWTPowerButton = uibutton(app.UIFigure, 'push');
            app.CWTPowerButton.ButtonPushedFcn = createCallbackFcn(app, @CWTPowerButtonPushed, true);
            app.CWTPowerButton.Text = 'CWT Power';
            app.PresetListBoxLabel = uilabel(app.UIFigure);
            app.PresetListBoxLabel.Text = 'Preset';
            app.PresetListBox = uilistbox(app.UIFigure);
            app.PresetListBox.ValueChangedFcn = createCallbackFcn(app, @PresetListBoxValueChanged, true);
            app.PresetListBox_2Label = uilabel(app.UIFigure);
            app.PresetListBox_2Label.Text = 'Preset';
            app.PresetListBox_2 = uilistbox(app.UIFigure);
            app.PresetListBox_2.ValueChangedFcn = createCallbackFcn(app, @PresetListBox_2ValueChanged, true);
            app.PresetListBox_2.Enable = 'off';
            app.RemoveTopPresetButton = uibutton(app.UIFigure, 'push');
            app.RemoveTopPresetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTopPresetButtonPushed, true);
            app.RemoveTopPresetButton.Text = '-';
            app.AddTopPresetButton = uibutton(app.UIFigure, 'push');
            app.AddTopPresetButton.ButtonPushedFcn = createCallbackFcn(app, @AddTopPresetButtonPushed, true);
            app.AddTopPresetButton.Text = '+';
            app.AddBottomPresetButton = uibutton(app.UIFigure, 'push');
            app.AddBottomPresetButton.ButtonPushedFcn = createCallbackFcn(app, @AddBottomPresetButtonPushed, true);
            app.AddBottomPresetButton.Text = '+';
            app.RemoveBottomPresetButton = uibutton(app.UIFigure, 'push');
            app.RemoveBottomPresetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBottomPresetButtonPushed, true);
            app.RemoveBottomPresetButton.Text = '-';
            app.LoadNewSessionsButton = uibutton(app.UIFigure, 'push');
            app.LoadNewSessionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewSessionsButtonPushed, true);
            app.LoadNewSessionsButton.Text = 'Load New Sessions';
            app.PrevSessionButton = uibutton(app.UIFigure, 'push');
            app.PrevSessionButton.ButtonPushedFcn = createCallbackFcn(app, @PrevSessionButtonPushed, true);
            app.PrevSessionButton.Text = 'Prev Session';
            app.NextSessionButton = uibutton(app.UIFigure, 'push');
            app.NextSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NextSessionButtonPushed, true);
            app.NextSessionButton.Text = 'Next Session';
            app.StimTypesListBoxLabel = uilabel(app.UIFigure);
            app.StimTypesListBoxLabel.Text = 'StimTypes';
            app.StimTypesListBox = uilistbox(app.UIFigure);
            app.StimTypesListBox.Multiselect = 'on';
            app.StimTypesListBox.ValueChangedFcn = createCallbackFcn(app, @StimTypesListBoxValueChanged, true);
            app.StimTypesListBox.Value = {'Item 1'};
            app.StimTypesListBox_2Label = uilabel(app.UIFigure);
            app.StimTypesListBox_2Label.Enable = 'off';
            app.StimTypesListBox_2Label.Text = 'StimTypes';
            app.StimTypesListBox_2 = uilistbox(app.UIFigure);
            app.StimTypesListBox_2.Multiselect = 'on';
            app.StimTypesListBox_2.ValueChangedFcn = createCallbackFcn(app, @StimTypesListBox_2ValueChanged, true);
            app.StimTypesListBox_2.Enable = 'off';
            app.StimTypesListBox_2.Value = {'Item 1'};
            app.RegionDropDownLabel = uilabel(app.UIFigure);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.UIFigure);
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.DisplayCluster = uilabel(app.UIFigure);
            app.DisplayCluster.Text = 'Cluster ID';
            app.DisplayChannel = uilabel(app.UIFigure);
            app.DisplayChannel.Text = 'Channel';
            app.DisplayRegion = uilabel(app.UIFigure);
            app.DisplayRegion.Text = 'Region';
            app.RemoveTopNeuronSetButton_2 = uibutton(app.UIFigure, 'push');
            app.RemoveTopNeuronSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveTopNeuronSetButton_2Pushed, true);
            app.RemoveTopNeuronSetButton_2.Text = '-';
            app.AddTopNeuronSetButton_2 = uibutton(app.UIFigure, 'push');
            app.AddTopNeuronSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddTopNeuronSetButton_2Pushed, true);
            app.AddTopNeuronSetButton_2.Text = '+';
            app.AddBottomNeuronSetButton_2 = uibutton(app.UIFigure, 'push');
            app.AddBottomNeuronSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddBottomNeuronSetButton_2Pushed, true);
            app.AddBottomNeuronSetButton_2.Text = '+';
            app.RemoveBottomNeuronSetButton_2 = uibutton(app.UIFigure, 'push');
            app.RemoveBottomNeuronSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveBottomNeuronSetButton_2Pushed, true);
            app.RemoveBottomNeuronSetButton_2.Text = '-';
            app.NeuronSetListBoxLabel = uilabel(app.UIFigure);
            app.NeuronSetListBoxLabel.Text = 'Neuron Set';
            app.NeuronSetListBox = uilistbox(app.UIFigure);
            app.NeuronSetListBox.ValueChangedFcn = createCallbackFcn(app, @NeuronSetListBoxValueChanged, true);
            app.NeuronSetListBox_2Label = uilabel(app.UIFigure);
            app.NeuronSetListBox_2Label.Text = 'Neuron Set';
            app.NeuronSetListBox_2 = uilistbox(app.UIFigure);
            app.NeuronSetListBox_2.ValueChangedFcn = createCallbackFcn(app, @NeuronSetListBox_2ValueChanged, true);
            app.NeuronSetListBox_2.Enable = 'off';
            app.SankeyInEditFieldLabel = uilabel(app.UIFigure);
            app.SankeyInEditFieldLabel.Visible = 'off';
            app.SankeyInEditFieldLabel.Text = 'Sankey In';
            app.SankeyInEditField = uieditfield(app.UIFigure, 'text');
            app.SankeyInEditField.ValueChangedFcn = createCallbackFcn(app, @SankeyInEditFieldValueChanged, true);
            app.SankeyInEditField.Visible = 'off';
            app.SankeyOutEditFieldLabel = uilabel(app.UIFigure);
            app.SankeyOutEditFieldLabel.Visible = 'off';
            app.SankeyOutEditFieldLabel.Text = 'Sankey Out';
            app.SankeyOutEditField = uieditfield(app.UIFigure, 'text');
            app.SankeyOutEditField.ValueChangedFcn = createCallbackFcn(app, @SankeyOutEditFieldValueChanged, true);
            app.SankeyOutEditField.Visible = 'off';
            app.BpodEventEditField_2 = uieditfield(app.UIFigure, 'text');
            app.BpodEventEditField_2.ValueChangedFcn = createCallbackFcn(app, @BpodEventEditField_2ValueChanged, true);
            app.WithinStateEditField_2 = uieditfield(app.UIFigure, 'text');
            app.WithinStateEditField_2.ValueChangedFcn = createCallbackFcn(app, @WithinStateEditField_2ValueChanged, true);
            app.PriorToStateEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PriorToStateEditField_2.ValueChangedFcn = createCallbackFcn(app, @PriorToStateEditField_2ValueChanged, true);
            app.ExcludeByStateEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ExcludeByStateEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExcludeByStateEditField_2ValueChanged, true);
            app.PriorToEventEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PriorToEventEditField_2.ValueChangedFcn = createCallbackFcn(app, @PriorToEventEditField_2ValueChanged, true);
            app.UpperPanelLabel = uilabel(app.UIFigure);
            app.UpperPanelLabel.Text = 'Upper Panel';
            app.LowerPanelLabel = uilabel(app.UIFigure);
            app.LowerPanelLabel.Text = 'Lower Panel';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SessionLabel = uilabel(app.UIFigure);
            app.SessionLabel.Text = 'Label2';
            app.ShowLegendCheckBox = uicheckbox(app.UIFigure);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.ShowLegendCheckBox_2 = uicheckbox(app.UIFigure);
            app.ShowLegendCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBox_2ValueChanged, true);
            app.ShowLegendCheckBox_2.Text = 'Show Legend';
            app.ShowLegendCheckBox_2.Value = true;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.TopSlider = uislider(app.UIFigure, 'range');
            app.TopSlider.ValueChangedFcn = createCallbackFcn(app, @TopSliderValueChanged, true);
            app.TopSlider.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.BottomSlider = uislider(app.UIFigure, 'range');
            app.BottomSlider.ValueChangedFcn = createCallbackFcn(app, @BottomSliderValueChanged, true);
            app.BottomSlider.Visible = 'off';
            app.SortPopTopCheckbox = uicheckbox(app.UIFigure);
            app.SortPopTopCheckbox.ValueChangedFcn = createCallbackFcn(app, @SortPopTopCheckboxValueChanged, true);
            app.SortPopTopCheckbox.Text = 'Sort Population Heatmaps';
            app.SortPopBottomCheckbox = uicheckbox(app.UIFigure);
            app.SortPopBottomCheckbox.ValueChangedFcn = createCallbackFcn(app, @SortPopBottomCheckboxValueChanged, true);
            app.SortPopBottomCheckbox.Text = 'Sort Population Heatmaps';
            app.FirstEventCheckBox = uicheckbox(app.UIFigure);
            app.FirstEventCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstEventCheckBoxValueChanged, true);
            app.FirstEventCheckBox.Text = 'First Event';
            app.LastEventCheckBox = uicheckbox(app.UIFigure);
            app.LastEventCheckBox.ValueChangedFcn = createCallbackFcn(app, @LastEventCheckBoxValueChanged, true);
            app.LastEventCheckBox.Text = 'Last Event';
            app.FirstEventCheckBox_2 = uicheckbox(app.UIFigure);
            app.FirstEventCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FirstEventCheckBox_2ValueChanged, true);
            app.FirstEventCheckBox_2.Visible = 'off';
            app.FirstEventCheckBox_2.Text = 'First Event';
            app.LastEventCheckBox_2 = uicheckbox(app.UIFigure);
            app.LastEventCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LastEventCheckBox_2ValueChanged, true);
            app.LastEventCheckBox_2.Visible = 'off';
            app.LastEventCheckBox_2.Text = 'Last Event';
            app.ColorLimitsLabel = uilabel(app.UIFigure);
            app.ColorLimitsLabel.Text = 'Color Limits Top';
            app.CLimSlider = uislider(app.UIFigure, 'range');
            app.CLimSlider.Limits = [-10 10];
            app.CLimSlider.MajorTicks = [-10 -5 0 5 10];
            app.CLimSlider.ValueChangedFcn = createCallbackFcn(app, @CLimSliderValueChanged, true);
            app.CLimSlider.MinorTicks = [-9 -8 -7 -6 -4 -3 -2 -1 1 2 3 4 6 7 8 9];
            app.CLimSlider.Value = [-3 3];
            app.ColorLimitsLabel_2 = uilabel(app.UIFigure);
            app.ColorLimitsLabel_2.Text = 'Color Limits Bottom';
            app.CLimSlider_2 = uislider(app.UIFigure, 'range');
            app.CLimSlider_2.Limits = [-10 10];
            app.CLimSlider_2.MajorTicks = [-10 -5 0 5 10];
            app.CLimSlider_2.ValueChangedFcn = createCallbackFcn(app, @CLimSlider_2ValueChanged, true);
            app.CLimSlider_2.MinorTicks = [-9 -8 -7 -6 -4 -3 -2 -1 1 2 3 4 6 7 8 9];
            app.CLimSlider_2.Value = [-3 3];
            app.MatchcLimCheckBox = uicheckbox(app.UIFigure);
            app.MatchcLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @MatchcLimCheckBoxValueChanged, true);
            app.MatchcLimCheckBox.Text = 'Match cLim';
            app.SortBottomByTopCheckBox = uicheckbox(app.UIFigure);
            app.SortBottomByTopCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortBottomByTopCheckBoxValueChanged, true);
            app.SortBottomByTopCheckBox.Text = 'Sort Bottom By Top';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbbasCentral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end