classdef LoadExpManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConfirmSelectionButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % The AbbasCentral app
        uiTable     %table storing vars
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.callingApp = caller;


            % Before calling the whos function from evalin, we'll do a 
            % security check that the whos function is the one provided
            % by Matlab.  This reduces the risk of using evalin().
            if isempty(regexp(which('whos'), 'built-in *.'))
                error('The built-in whos() function is being overshaddowed.  Do not proceed.')
            end
            baseVarInfo = evalin('base', 'whos');
            matches =  strcmp({baseVarInfo.class}, 'ExpManager');
            goodVars = {baseVarInfo(matches).name};
            baseVarInfo = rmfield(baseVarInfo,{'nesting','global','size','sparse','complex','persistent'});
            baseVarCell = [num2cell(false(numel(goodVars), 1)),struct2cell(baseVarInfo(matches))']; % selection column
            varNames = [{'Select'}; fieldnames(baseVarInfo)];
            goodVarTable = cell2table(baseVarCell, 'RowNames', goodVars, 'VariableNames', varNames);
            % Get base workspace variable inf
            fig = uifigure('position', [420 420 550, 350], 'Visible', 'off');
            uit = uitable(fig, 'position', [20 20 500, 300],...
                'ColumnEditable', [true, false(1,1)], 'Tag', 'BaseWorkspaceVarInfo');
            uit.Data = goodVarTable; 
            copyobj(uit, app.UIFigure)   
            close(fig)
            appTable = app.UIFigure.Children(1);
            % Find the handle to the UITable (note the tag name assigned to the UITable)
            % This step is avoidable if the UITable handle is already stored in app.
            app.uiTable = findall(appTable, 'Tag', 'BaseWorkspaceVarInfo'); 
            % Get the user's selection and extract the variable names (varsToLoad).

            
            waitfor(app.ConfirmSelectionButton, 'UserData', true);


        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)
            selection = app.uiTable.Data.Select; 
            varsToLoad = app.uiTable.RowName(selection); 
            % loop through each variable and store its values in a cell array. 
            varValues = cell(size(varsToLoad)); 
            for i = 1:numel(varsToLoad)
                varValues{i} = evalin('base', varsToLoad{i}); 
            end
            app.callingApp.experiment = varValues{1};
            % app.callingApp.sessions = varValues{1}.sessions;
            % app.callingApp.metadata = varValues{1}.metadata;
            delete(app.callingApp.TopPanel.Children)
            delete(app.callingApp.BottomPanel.Children)
            app.callingApp.UIFigure.Visible = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.callingApp.UIFigure.Visible = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfirmSelectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = 'Confirm Selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadExpManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end